classdef Zygo_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZygoLaserMeasurementSystemProgrammedbyAmmarAlJodahUIFigure  matlab.ui.Figure
        SystemSetupPanel               matlab.ui.container.Panel
        NumberofAxisKnobLabel          matlab.ui.control.Label
        NumberofAxisKnob               matlab.ui.control.DiscreteKnob
        RangeKnobLabel                 matlab.ui.control.Label
        RangeKnob                      matlab.ui.control.DiscreteKnob
        InitializeSystemButton         matlab.ui.control.Button
        AxisResetButton                matlab.ui.control.Button
        Axis1Panel_2                   matlab.ui.container.Panel
        KpDropDownLabel                matlab.ui.control.Label
        KpDropDown_1                   matlab.ui.control.DropDown
        KvDropDownLabel                matlab.ui.control.Label
        KvDropDown_1                   matlab.ui.control.DropDown
        DirDropDownLabel               matlab.ui.control.Label
        DirDropDown_1                  matlab.ui.control.DropDown
        Axis2Panel_2                   matlab.ui.container.Panel
        KpDropDown_2Label              matlab.ui.control.Label
        KpDropDown_2                   matlab.ui.control.DropDown
        KvDropDown_2Label              matlab.ui.control.Label
        KvDropDown_2                   matlab.ui.control.DropDown
        DirDropDown_2Label             matlab.ui.control.Label
        DirDropDown_2                  matlab.ui.control.DropDown
        Axis3Panel_2                   matlab.ui.container.Panel
        KpDropDown_3Label              matlab.ui.control.Label
        KpDropDown_3                   matlab.ui.control.DropDown
        KvDropDown_3Label              matlab.ui.control.Label
        KvDropDown_3                   matlab.ui.control.DropDown
        DirDropDown_3Label             matlab.ui.control.Label
        DirDropDown_3                  matlab.ui.control.DropDown
        Axis4Panel_2                   matlab.ui.container.Panel
        KpDropDown_4Label              matlab.ui.control.Label
        KpDropDown_4                   matlab.ui.control.DropDown
        KvDropDown_4Label              matlab.ui.control.Label
        KvDropDown_4                   matlab.ui.control.DropDown
        DirDropDown_4Label             matlab.ui.control.Label
        DirDropDown_4                  matlab.ui.control.DropDown
        Axis5Panel_2                   matlab.ui.container.Panel
        KpDropDown_5Label              matlab.ui.control.Label
        KpDropDown_5                   matlab.ui.control.DropDown
        KvDropDown_5Label              matlab.ui.control.Label
        KvDropDown_5                   matlab.ui.control.DropDown
        DirDropDown_5Label             matlab.ui.control.Label
        DirDropDown_5                  matlab.ui.control.DropDown
        Axis6Panel_2                   matlab.ui.container.Panel
        KpDropDown_6Label              matlab.ui.control.Label
        KpDropDown_6                   matlab.ui.control.DropDown
        KvDropDown_6Label              matlab.ui.control.Label
        KvDropDown_6                   matlab.ui.control.DropDown
        DirDropDown_6Label             matlab.ui.control.Label
        DirDropDown_6                  matlab.ui.control.DropDown
        Axis7Panel_2                   matlab.ui.container.Panel
        KpDropDown_7Label              matlab.ui.control.Label
        KpDropDown_7                   matlab.ui.control.DropDown
        KvDropDown_7Label              matlab.ui.control.Label
        KvDropDown_7                   matlab.ui.control.DropDown
        DirDropDown_7Label             matlab.ui.control.Label
        DirDropDown_7                  matlab.ui.control.DropDown
        Axis8Panel_2                   matlab.ui.container.Panel
        KpDropDown_8Label              matlab.ui.control.Label
        KpDropDown_8                   matlab.ui.control.DropDown
        KvDropDown_8Label              matlab.ui.control.Label
        KvDropDown_8                   matlab.ui.control.DropDown
        DirDropDown_8Label             matlab.ui.control.Label
        DirDropDown_8                  matlab.ui.control.DropDown
        NumberofSamplesKnobLabel       matlab.ui.control.Label
        NumberofSamplesKnob            matlab.ui.control.DiscreteKnob
        HumidityEditField              matlab.ui.control.NumericEditField
        TemperatureEditField           matlab.ui.control.NumericEditField
        TemperatureDegCGaugeLabel      matlab.ui.control.Label
        TemperatureGauge               matlab.ui.control.Gauge
        PressuremmHgGaugeLabel         matlab.ui.control.Label
        PressureGauge                  matlab.ui.control.Gauge
        HumidityGaugeLabel             matlab.ui.control.Label
        HumidityGauge                  matlab.ui.control.Gauge
        PressureEditField              matlab.ui.control.NumericEditField
        ZygoOffButton                  matlab.ui.control.Button
        SystemStatusPanel              matlab.ui.container.Panel
        Panel_2                        matlab.ui.container.Panel
        MeasurementSignalErrorLabel    matlab.ui.control.Label
        Panel_3                        matlab.ui.container.Panel
        ReferenceSignalErrorLabel      matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        MeasurementErrorLabel          matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        ReferenceErrorLabel            matlab.ui.control.Label
        Panel_6                        matlab.ui.container.Panel
        OverflowErrorLabel             matlab.ui.control.Label
        Panel_7                        matlab.ui.container.Panel
        VelocityErrorLabel             matlab.ui.control.Label
        Axis1Panel                     matlab.ui.container.Panel
        LedMSE                         matlab.ui.control.Lamp
        LedRSE                         matlab.ui.control.Lamp
        LedME                          matlab.ui.control.Lamp
        LedRE                          matlab.ui.control.Lamp
        LedOFE                         matlab.ui.control.Lamp
        LedVE                          matlab.ui.control.Lamp
        Axis2Panel                     matlab.ui.container.Panel
        LedMSE_2                       matlab.ui.control.Lamp
        LedRSE_2                       matlab.ui.control.Lamp
        LedME_2                        matlab.ui.control.Lamp
        LedRE_2                        matlab.ui.control.Lamp
        LedOFE_2                       matlab.ui.control.Lamp
        LedVE_2                        matlab.ui.control.Lamp
        Axis3Panel                     matlab.ui.container.Panel
        LedMSE_3                       matlab.ui.control.Lamp
        LedRSE_3                       matlab.ui.control.Lamp
        LedME_3                        matlab.ui.control.Lamp
        LedRE_3                        matlab.ui.control.Lamp
        LedOFE_3                       matlab.ui.control.Lamp
        LedVE_3                        matlab.ui.control.Lamp
        Axis4Panel                     matlab.ui.container.Panel
        LedMSE_4                       matlab.ui.control.Lamp
        LedRSE_4                       matlab.ui.control.Lamp
        LedME_4                        matlab.ui.control.Lamp
        LedRE_4                        matlab.ui.control.Lamp
        LedOFE_4                       matlab.ui.control.Lamp
        LedVE_4                        matlab.ui.control.Lamp
        Axis5Panel                     matlab.ui.container.Panel
        LedMSE_5                       matlab.ui.control.Lamp
        LedRSE_5                       matlab.ui.control.Lamp
        LedME_5                        matlab.ui.control.Lamp
        LedRE_5                        matlab.ui.control.Lamp
        LedOFE_5                       matlab.ui.control.Lamp
        LedVE_5                        matlab.ui.control.Lamp
        Axis6Panel                     matlab.ui.container.Panel
        LedMSE_6                       matlab.ui.control.Lamp
        LedRSE_6                       matlab.ui.control.Lamp
        LedME_6                        matlab.ui.control.Lamp
        LedRE_6                        matlab.ui.control.Lamp
        LedOFE_6                       matlab.ui.control.Lamp
        LedVE_6                        matlab.ui.control.Lamp
        Axis7Panel                     matlab.ui.container.Panel
        LedMSE_7                       matlab.ui.control.Lamp
        LedRSE_7                       matlab.ui.control.Lamp
        LedME_7                        matlab.ui.control.Lamp
        LedRE_7                        matlab.ui.control.Lamp
        LedOFE_7                       matlab.ui.control.Lamp
        LedVE_7                        matlab.ui.control.Lamp
        Axis8Panel                     matlab.ui.container.Panel
        LedMSE_8                       matlab.ui.control.Lamp
        LedRSE_8                       matlab.ui.control.Lamp
        LedME_8                        matlab.ui.control.Lamp
        LedRE_8                        matlab.ui.control.Lamp
        LedOFE_8                       matlab.ui.control.Lamp
        LedVE_8                        matlab.ui.control.Lamp
        Panel_8                        matlab.ui.container.Panel
        ErrorTypeLabel                 matlab.ui.control.Label
        SystemMessagesPanel            matlab.ui.container.Panel
        ListBox                        matlab.ui.control.ListBox
        AxisLiveUpdatePanel            matlab.ui.container.Panel
        Axis1positionEditField         matlab.ui.control.NumericEditField
        Axis2positionEditField         matlab.ui.control.NumericEditField
        Axis3positionEditField         matlab.ui.control.NumericEditField
        Axis4positionEditField         matlab.ui.control.NumericEditField
        Axis5positionEditField         matlab.ui.control.NumericEditField
        Axis6positionEditField         matlab.ui.control.NumericEditField
        Axis7positionEditField         matlab.ui.control.NumericEditField
        Axis8positionEditField         matlab.ui.control.NumericEditField
        Axis1velocityEditField         matlab.ui.control.NumericEditField
        Axis2velocityEditField         matlab.ui.control.NumericEditField
        Axis3velocityEditField         matlab.ui.control.NumericEditField
        Axis4velocityEditField         matlab.ui.control.NumericEditField
        Axis5velocityEditField         matlab.ui.control.NumericEditField
        Axis6velocityEditField         matlab.ui.control.NumericEditField
        Axis7velocityEditField         matlab.ui.control.NumericEditField
        Axis8velocityEditField         matlab.ui.control.NumericEditField
        AxisLabel                      matlab.ui.control.Label
        PositionLabel                  matlab.ui.control.Label
        VelocityLabel                  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Axis1Switch                    matlab.ui.control.Switch
        Axis2Switch                    matlab.ui.control.Switch
        Axis3Switch                    matlab.ui.control.Switch
        Axis4Switch                    matlab.ui.control.Switch
        Axis5Switch                    matlab.ui.control.Switch
        Axis6Switch                    matlab.ui.control.Switch
        Axis7Switch                    matlab.ui.control.Switch
        Axis8Switch                    matlab.ui.control.Switch
        PlotPositionsSwitchLabel       matlab.ui.control.Label
        PlotPositionsSwitch            matlab.ui.control.ToggleSwitch
        Panel_9                        matlab.ui.container.Panel
        ZygoLaserMeasurementSystemLabel_2  matlab.ui.control.Label
        programmedbyAmmarAlJodahLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %% ======CONSTANTS ========
        ZYGO_MANF_ID							=hex2dec('1000'); 	% Zygo VXI manufacturer ID of device
        ZYGO_MODEL_CODE							=hex2dec('1010');	% model code of device ZMI2402
        
        % Zygo VME bus offset address
        VME_COMMAND_REG							=hex2dec('00');	% W
        VME_STATUS_REG							=hex2dec('00');	% R
        VME_INTERRUPT_ENABLE_REG				=hex2dec('02'); % RW
        VME_AUX_DATA_ADDR_REG					=hex2dec('04'); % W
        VME_ERROR_STATUS_REG					=hex2dec('04'); % R
        VME_INTERRUPT_VECTOR_REG				=hex2dec('06'); % RW
        VME_CONTROL_01_REG						=hex2dec('08'); % RW
        VME_CONTROL_02_REG						=hex2dec('0A');	% RW
        
        VME_SAMPLE_TIMER_REG					=hex2dec('0C'); % rW
        VME_DIAG_ADC_REG_M0                     =hex2dec('0C');	% R  M0
        VME_SAMPLE_TIMER_REG_M1					=hex2dec('0C'); % R  M1
        VME_OFFSET_MSB_REG_M2					=hex2dec('0C'); % R  M2
        VME_QUICK_RESET_MSB_REG_M3				=hex2dec('0C');	% R  M3
        VME_PHASE_METER_DIAG_REG_M4             =hex2dec('0C');	% R  M4
        VME_P2_INTERRUPT_MASK_REG_M5			=hex2dec('0C');	% R  M5
        VME_AUX_CONFIG_REG_M6					=hex2dec('0C');	% R  M6
        VME_AUX_DATA_READ_REG_M7				=hex2dec('0C'); % R  M7
        
        VME_HIGH_NIBBLES_REG					=hex2dec('0E');	% rW
        VME_HIGH_NIBBLES_REG_M0					=hex2dec('0E');	% R  M0
        VME_USER_EXCESS_VELOCITY_REG_M1			=hex2dec('0E'); % R  M1
        VME_OFFSET_LSB_REG_M2					=hex2dec('0E'); % R  M2
        VME_QUICK_RESET_LSB_REG_M3				=hex2dec('0E');	% R  M3
        VME_DATA_AGE_ADJUST_REG_M4				=hex2dec('0E'); % R  M4
        VME_EEPROM_DATA_READ_REG_M5				=hex2dec('0E'); % R  M5
        VME_QUICK_RESET_HIGH_REG_M6				=hex2dec('0E'); % R  M6
        VME_AUX_STATUS_REG_M7					=hex2dec('0E'); % R  M7
        
        VME_OFFSET_MSB_REG						=hex2dec('10'); % rW
        VME_OFFSET_LSB_REG						=hex2dec('12'); % rW
        VME_POSITION_MSB_REG					=hex2dec('10'); % R
        VME_POSITION_LSB_REG					=hex2dec('12');	% R
        VME_DATA_AGE_ADJUST_REG                 =hex2dec('14');	% rW
        VME_DIAG_ADC_MUX_REG					=hex2dec('16');	% W
        VME_SAMPLED_POSITION_MSB_REG			=hex2dec('14'); % R
        VME_SAMPLED_POSITION_LSB_REG			=hex2dec('16'); % R
        VME_USER_EXCESS_VELOCITY_REG			=hex2dec('18');	% rW
        VME_P2_INTERRUPT_MASK_REG				=hex2dec('1A');	% rW
        VME_VELOCITY_MSB_REG					=hex2dec('18');	% R
        VME_VELOCITY_LSB_REG					=hex2dec('1A'); % R
        VME_EEPROM_CONTROL_REG					=hex2dec('1C'); % W
        VME_EEPROM_DATA_WRITE_REG				=hex2dec('1E');	% W
        VME_AUX_DATA_WRITE_REG					=hex2dec('1E'); % W
        VME_TIME_MSB_REG						=hex2dec('1C');	% R
        VME_TIME_LSB_REG						=hex2dec('1E');	% R
        
        % Zygo Aux Data offset address
        VME_ADA_AUX_CONTROL_00_REG				=hex2dec('00'); % RW
        VME_ADA_FIRMWARE_VERSION_REG			=hex2dec('01'); % R
        VME_ADA_FIRMWARE_REVISION_REG			=hex2dec('02'); % R
        VME_ADA_ABSOLUTE_PHASE_REG				=hex2dec('03'); % R
        VME_ADA_SSI_OFFSET_REG					=hex2dec('04'); % R
        VME_ADA_SSI_MAX_REG						=hex2dec('05'); % R
        VME_ADA_SSI_MIN_REG						=hex2dec('06'); % R
        VME_ADA_SSI_SQUELCH_REG					=hex2dec('07'); % RW
        VME_ADA_PHASE_NOISE_PEAK_REG			=hex2dec('08'); % R
        VME_ADA_SW2345_REG						=hex2dec('09'); % R
        VME_ADA_SW67_REG						=hex2dec('0A'); % R
        VME_ADA_SW78_REG						=hex2dec('0B'); % R
        VME_ADA_SSI_DAC_TEST_REG				=hex2dec('0C'); % RW
        VME_ADA_TEST_STATUS_REG                 =hex2dec('0D'); % R
        VME_ADA_DIAG_RAM_START_REG		 		=hex2dec('0E'); % R
        
        VME_ADA_AUX_CONTROL_01_REG				=hex2dec('20'); % RW
        VME_ADA_DIAG_TEMP_MONITOR_READ_REG		=hex2dec('21'); % R
        VME_ADA_DIAG_TEMP_MONITOR_WRITE_REG		=hex2dec('22'); % RW
        VME_ADA_DIAG_TEMP_MONITOR_CONTROL_REG	=hex2dec('23'); % RW
        VME_ADA_ADC_CONTROL_REG					=hex2dec('24'); % RW
        VME_ADA_ADC_READ_REG					=hex2dec('25'); % R
        VME_ADA_ADC_WRITE_REG					=hex2dec('26'); % RW
        VME_ADA_TEST_SW8_REG					=hex2dec('27'); % RW
        VME_ADA_TEST_SW7_REG					=hex2dec('28'); % RW
        VME_ADA_TEST_SW6_REG					=hex2dec('29'); % RW
        
        % Zygo system constants
        ZYGO_SAMPLE_PERIOD                      =0.00025;  % 4.0 kHz sample period in seconds
        
        % EEPROM data at 0X01
        ZYGO_DAY_MANUFACTURE_MASK				=hex2dec('001F'); % bits 0-4  are day of manufacture
        ZYGO_MONTH_MANUFACTURE_MASK             =hex2dec('01E0'); %bits 5-8  are month of manufacture
        ZYGO_YEAR_MANUFACTURE_MASK				=hex2dec('1E00'); %bits 9-12 are year of manufacture + 1996
        ZYGO_BOARD_MODEL_CODE_MASK				=hex2dec('E000'); %bits 13-15 are board model code
        
        ZYGO_BOARD_MODEL_CODE_2401				=hex2dec('2000'); %	0000
        ZYGO_BOARD_MODEL_CODE_2402				=hex2dec('4000'); %	2000
        ZYGO_BOARD_MODEL_CODE_2404				=hex2dec('8000'); %	4000
        
        
        ZYGO_STRING_SIZE						=256;		%size of data string or error string
        
        ZYGO_RECORD_DATA_SIZE					=2000000;	%size of recorded data
        
        ZYGO_OFF_LINE							=0;         %for device on/off line
        ZYGO_ON_LINE							=1;
        
        % return of Zygo function
        ZYGO_OK							     	=0;
        ZYGO_ERROR								=1;
        ZYGO_LOOP_CTRL_COUNT_MAX				=5000;
        
        %time in sec, one count = 25 nsec
        ZYGO_TIME_COUNT_TO_SECOND               =0.000000025;
        
        ZYGO_LAMBDA_F1_VACUUM_VERTICAL			=632.991501; 	% nm
        ZYGO_LAMBDA_F2_VACUUM_HORIZONTAL		=632.991528; 	% nm
        
        ZYGO_DEFAULT_TEMPERATURE				=20;            % Deg C
        ZYGO_DEFAULT_PRESSURE					=760;           % mm Hg
        ZYGO_DEFAULT_RELATIVE_HUMIDITY			=50;            % %
        
        ZYGO_RETROSPACING_COMPACT_2_AXES_HSPMI	=12.688;        % mm
        ZYGO_WAVELENGTH_COMPENSATOR_LENGTH		=70.000;        % mm
        
        ZYGO_PI								=3.1415926535897932384626433832795;
        %% Zygo Structure
        VMEDevice=[visa('ni','VXI0::400::INSTR'),visa('ni','VXI0::400::INSTR'),visa('ni','VXI0::400::INSTR'),visa('ni','VXI0::400::INSTR')];
        
        
        positionInt              =zeros(1,8); %unit: nano metre (nm)
        velocityInt              =zeros(1,8);		%  unit: nm / sec
        timeInt                  =zeros(1,8);		%  unit: sec
        timeIntPrev              =zeros(1,8);		%  unit: sec
        timeIndex                =zeros(1,8);		%  number of times for time to cross-over 0xFFFFFFFF
        timeFirstInt             =zeros(1,8);		%  number of times for time to cross-over 0xFFFFFFFF
        positionrecored;
        positionDouble			 =zeros(1,8);		%  unit: nano metre (nm)
        velocityDouble			 =zeros(1,8);		%  unit: nm / sec
        timeDouble 				 =zeros(1,8);		%  unit: sec
        
        
        delayMeasurement          =zeros(1,8);		%
        delayReference			 =zeros(1,8);		%
        dataAge              	 =zeros(1,8);		%
        dataAgeAdjust= 0;
        
        vmeInterruptLevel		 =zeros(1,8);		%
        posDirectionSense		 =zeros(1,8);		%
        controlRegisterOneData	 =zeros(1,8);		%
        controlRegisterTwoData	 =zeros(1,8);		%
        
        filterKP                 =zeros(1,8);		%
        filterKV                 =zeros(1,8);		%
        
        errorStatusReg			 =zeros(1,8);		%
        statusRegister			 =zeros(1,8);		%
        
        flagMeasurementNoSignal	 =zeros(1,8);		% no signal = 1, normal = 0
        flagReferenceNoSignal	 =zeros(1,8);		% no signal = 1, normal = 0
        flagMeasurementError     =zeros(1,8);		% error = 1, normal = 0
        flagReferenceError		 =zeros(1,8);		% error = 1, normal = 0
        flagOverFlowError		 =zeros(1,8);		% error = 1, normal = 0
        flagVelocityError		 =zeros(1,8);		% error = 1, normal = 0
        
        
        zygoAxisDeviceNumber     =zeros(1,8);
        zygoAxisOffsetAddr		 =zeros(1,8);
        
        selectedAxes             =zeros(1,8); 		% selected = 1, not selected = 0
        selectedPosVel			 =zeros(1,8); 		% pos-&-vel= 1, pos-only     = 2
        selectedFreq             =zeros(1,8); 		% f1       = 1, f2           = 2
        selectedWriteFilePhyRaw  =zeros(1,8);		% physical = 1, raw          = 2
        selectedLinearAngular    =zeros(1,8); 		% linear   = 1, angular      = 2
        selectedPosScale         =zeros(1,8); 		% nm   = 1, um   = 2, mm   = 3, m   = 4 OR n-deg   = 1, ...
        selectedVelScale         =zeros(1,8); 		% nm/s = 1, um/s = 2, mm/s = 3, m/s = 4 OR n-deg/s = 1, ...
        
        wavelengthCompensationYesNo=0;                           % yes      = 1, no           = 0
        wavelengthCompensationFreq=0;                            % f1       = 1, f2           = 2
        wavelengthCompensationAxis=0;
        
        temperatureDegC=0;
        pressureMmHg=0;
        relativeHumidityPercent=0;
        retrospacingCompact2AxesHSPMIMm=0;
        wavelengthCompensatorLengthMm=0;                         % L = 70 mm +/- ...
        
        wavelengthCompRefractiveIndex=0;
        initialRefractiveIndex=0;
        initialLambda_F1=0;
        initialLambda_F2=0;
        positionF1Count2Nm=0;                                     % Nanometre
        positionF2Count2Nm=0;
        velocityF1Count2NmPerSec=0;
        velocityF2Count2NmPerSec=0;
        angleF1Count2TanQ=0;
        angleF2Count2TanQ=0;
        angVelF1Count2TanQPerSec=0;
        angVelF2Count2TanQPerSec=0;
        
        samplePeriod=0;
        numberOfAxes=4;
        axisNumber=0;
        
        eepromDataAddr=0;                                    % EEPROM address
        eepromDataU16=0;                                     % EEPROM data
        
        vmeDeviceNumber=0;
        vmeBusDataU16=0;
        vmeBusDataU32=0;
        vmeBusOffsetAddr=0;
        
        vmeBusRegisterAddr=0;                                % VME bus register or offset address
        vmeBusRegisterDataU16=0;                             % VME bus register data
        
        systemMessage='system error' ; 	% report system error message based on the below error code
        ph1;
        ph2;
        myTimer % Description
    end
    
    methods (Access = private)
        
        
        %% Hardware functions
        function  zygo_fnc_out= RegisterRead16BitData(app)
            try
                app.vmeBusDataU16 = memread(app.VMEDevice(app.vmeDeviceNumber),app.vmeBusOffsetAddr,'uint16');
                zygo_fnc_out= app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        function  zygo_fnc_out= RegisterRead32BitData(app)
            try
                app.vmeBusDataU32= memread(app.VMEDevice(app.vmeDeviceNumber),app.vmeBusOffsetAddr,'uint32');
                app.vmeBusDataU32= typecast(uint32(app.vmeBusDataU32), 'int32');
                zygo_fnc_out= app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        function  zygo_fnc_out= RegisterWrite16BitData(app)
            try
                memwrite(app.VMEDevice(app.vmeDeviceNumber),app.vmeBusDataU16,app.vmeBusOffsetAddr,'uint16');
                zygo_fnc_out= app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        function  zygo_fnc_out= RegisterWrite32BitData(app)
            try
                memwrite(app.VMEDevice(app.vmeDeviceNumber),app.vmeBusDataU16,app.vmeBusOffsetAddr,'uint32');
                zygo_fnc_out= app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        %%  Zygo Functions
        
        function  zygo_fnc_out= ZygoInitialize(app)
            try
                app.samplePeriod = app.ZYGO_SAMPLE_PERIOD;
                % get up Zygo axis addresses, interrupt levels, position direction sense, and SCLK enable
                for (axis = 1:app.numberOfAxes)
                    
                    if(axis == 1)  app.zygoAxisDeviceNumber(1)  = 1; end
                    if(axis == 2)  app.zygoAxisDeviceNumber(2)  = 1; end
                    if(axis == 3)  app.zygoAxisDeviceNumber(3)  = 2; end
                    if(axis == 4)  app.zygoAxisDeviceNumber(4)  = 2; end
                    if(axis == 5)  app.zygoAxisDeviceNumber(5)  = 3; end
                    if(axis == 6)  app.zygoAxisDeviceNumber(6)  = 3; end
                    if(axis == 7)  app.zygoAxisDeviceNumber(7)  = 4; end
                    if(axis == 8)  app.zygoAxisDeviceNumber(8)  = 4; end
                    
                    
                    if(axis == 1)  app.zygoAxisOffsetAddr(1)  = hex2dec('00'); end
                    if(axis == 2)  app.zygoAxisOffsetAddr(2)  = hex2dec('20'); end
                    if(axis == 3)  app.zygoAxisOffsetAddr(3)  = hex2dec('00'); end
                    if(axis == 4)  app.zygoAxisOffsetAddr(4)  = hex2dec('20'); end
                    if(axis == 5)  app.zygoAxisOffsetAddr(5)  = hex2dec('00'); end
                    if(axis == 6)  app.zygoAxisOffsetAddr(6)  = hex2dec('20'); end
                    if(axis == 7)  app.zygoAxisOffsetAddr(7)  = hex2dec('00'); end
                    if(axis == 8)  app.zygoAxisOffsetAddr(8)  = hex2dec('20'); end
                    
                    
                    if(axis == 1)  app.vmeInterruptLevel(1)  = 1; end
                    if(axis == 2)  app.vmeInterruptLevel(2)  = 1; end
                    if(axis == 3)  app.vmeInterruptLevel(3)  = 2; end
                    if(axis == 4)  app.vmeInterruptLevel(4)  = 2; end
                    if(axis == 5)  app.vmeInterruptLevel(5)  = 3; end
                    if(axis == 6)  app.vmeInterruptLevel(6)  = 3; end
                    if(axis == 7)  app.vmeInterruptLevel(7)  = 4; end
                    if(axis == 8)  app.vmeInterruptLevel(8)  = 4; end
                    
                    
                end
                zygo_fnc_out=app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        function  zygo_fnc_out= ZygoOffline(app)
            try
                fclose(app.VMEDevice);
                zygo_fnc_out=app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        function zygo_fnc_out= ZygoEepromReadData(app)
            try
                axis =app.axisNumber;
                % get VME device number
                app.vmeDeviceNumber =app.zygoAxisDeviceNumber(axis);
                % set up to read EEPROM by writing to EEPROM control register
                app.vmeBusOffsetAddr = app.VME_EEPROM_CONTROL_REG;
                app.vmeBusDataU16	  = hex2dec('0080') + (bitand(app.eepromDataAddr , hex2dec('003F')));
                if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                % wait for EEPROM busy to go low by reading from status register
                app.vmeBusOffsetAddr = app.VME_STATUS_REG;
                loopCount = 0;
                statusInt = hex2dec('0080');
                while(bitand(statusInt , hex2dec('0080')))
                    if(RegisterRead16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    statusInt =app.vmeBusDataU16;
                    if( loopCount >=  app.ZYGO_LOOP_CTRL_COUNT_MAX )
                        printmsg(app,'Error: Zygo reads EEPROM failed!');
                        zygo_fnc_out=app.ZYGO_ERROR; return;
                    end
                    loopCount=loopCount+1;
                    pause(0.002);	% 2 ms delay
                end
                
                % read from the interrupt vector register and put the data into reg6Int
                app.vmeBusOffsetAddr = app.VME_INTERRUPT_VECTOR_REG;
                if(RegisterRead16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                reg6Int =app.vmeBusDataU16;
                
                % write to set up M5 in the interrupt vector register
                app.vmeBusDataU16 = bitand( reg6Int , hex2dec('F8FF') ) + hex2dec('0500');
                if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                
                % read from the EEPROM data read register
                app.vmeBusOffsetAddr = app.VME_EEPROM_DATA_READ_REG_M5;
                if(RegisterRead16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                app.eepromDataU16 =app.vmeBusDataU16;
                
                % restore the interrupt vector register with reg6Int
                app.vmeBusOffsetAddr = app.VME_INTERRUPT_VECTOR_REG;
                app.vmeBusDataU16  = reg6Int;
                if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                
                zygo_fnc_out= app.ZYGO_OK;
                
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end


        function zygo_fnc_out= ZygoGetDataAge(app)
            try
                % get calibration data from the on-board EEPROM
                for(axis = 1:app.numberOfAxes)
                    % get axis number
                    app.axisNumber = axis;
                    
                    if(app.zygoAxisOffsetAddr(axis) == hex2dec('20'))
                        % board axis two
                        app.eepromDataAddr = hex2dec('10');
                        if(ZygoEepromReadData(app) ~= app.ZYGO_OK);zygo_fnc_out=app.ZYGO_ERROR; return;end
                        app.delayReference(axis)   =bitand( app.eepromDataU16 , hex2dec('003F'));	% ref delay
                        app.eepromDataAddr = hex2dec('11');
                        if(ZygoEepromReadData(app) ~= app.ZYGO_OK);zygo_fnc_out=app.ZYGO_ERROR; return;end
                        app.delayMeasurement(axis) = int32(app.eepromDataU16 / 256);	    % mea delay
                        
                    else
                        % board axis one
                        app.eepromDataAddr = hex2dec('06');
                        if(ZygoEepromReadData(app) ~= app.ZYGO_OK);zygo_fnc_out=app.ZYGO_ERROR; return;end
                        app.delayReference(axis)  = bitand(app.eepromDataU16 , hex2dec('003F'));	% ref delay
                        app.eepromDataAddr = hex2dec('07');
                        if(ZygoEepromReadData(app) ~= app.ZYGO_OK);zygo_fnc_out=app.ZYGO_ERROR; return;end
                        app.delayMeasurement(axis) = int32(app.eepromDataU16 / 256);	    % mea delay
                    end
                    
                    % calculate data age adjustments
                    % board #1
                    if(axis ==  1); app.dataAge (1) = int32(app.delayMeasurement (1));end
                    if(axis ==  2); app.dataAge (2) = int32(app.delayMeasurement (2));end
                    % board #2
                    if(axis ==  3); app.dataAge (3) = int32(app.delayMeasurement (3) - app.delayReference(3));end
                    if(axis ==  4); app.dataAge (4) = int32(app.delayMeasurement (4) - app.delayReference(3));end
                    % board #3
                    if(axis ==  5); app.dataAge (5) = int32(app.delayMeasurement (5) - app.delayReference(3) - app.delayReference(5));end
                    if(axis ==  6); app.dataAge (6) = int32(app.delayMeasurement (6) - app.delayReference(3) - app.delayReference(5));end
                    % board #4
                    if(axis ==  7); app.dataAge (7) = int32(app.delayMeasurement (7) - app.delayReference(3) - app.delayReference(5) - app.delayReference(7));end
                    if(axis ==  8); app.dataAge (8) = int32(app.delayMeasurement (8) - app.delayReference(3) - app.delayReference(5) - app.delayReference(7));end
                end
                
                % find max and min of adjustments
                minAdjust =  9999;
                maxAdjust = -9999;
                for(axis = 1: app.numberOfAxes)
                    % find max
                    if(app.dataAge(axis) > maxAdjust); maxAdjust = app.dataAge(axis);end
                    % find min
                    if(app.dataAge(axis) < minAdjust); minAdjust = app.dataAge(axis);end
                end
                
                % offset adjustments so range is near zero
                % find middle
                app.dataAgeAdjust = int32((maxAdjust + minAdjust) / 2);
                               % get data adjustments with offset
                for(axis = 1: app.numberOfAxes)
                    app.dataAge(axis) = bitand(int16(app.dataAgeAdjust - app.dataAge(axis)) , hex2dec('01FF'));
                end
                zygo_fnc_out= app.ZYGO_OK;
                
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        
        function zygo_fnc_out= ZygoRegisterInitialiseAxisReset(app)
            try
                
                % initialise all axis registers
                for(axis = 1:app.numberOfAxes)
                    % get VME device number
                    app.vmeDeviceNumber = app.zygoAxisDeviceNumber(axis);
                    
                    % disable VME interrupts
                    app.vmeBusOffsetAddr = app.VME_INTERRUPT_ENABLE_REG + app.zygoAxisOffsetAddr(axis);
                    app.vmeBusDataU16	  = hex2dec('0080');
                    if(RegisterRead16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    
                    % interrupt vector
                    app.vmeBusOffsetAddr = app.VME_INTERRUPT_VECTOR_REG + app.zygoAxisOffsetAddr(axis);
                    app.vmeBusDataU16    = hex2dec('1000') * app.vmeInterruptLevel(axis) + axis;
                    if(RegisterRead16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    
                    % control register 1, set P2 SCK0 output enable on all axis except the last one,  set clock disable on before last axis (numberOfAxes-1) axis only
                    app.vmeBusOffsetAddr = app.VME_CONTROL_01_REG + app.zygoAxisOffsetAddr(axis);
                    % the code commented below is for VME setting
                    %#############################################
                    %                 if( axis == (app.numberOfAxes-1) )
                    %                     app.vmeBusDataU16 = hex2dec('0C00') + hex2dec('0078');
                    %                 else
                    %                     app.vmeBusDataU16 = hex2dec('0400') + hex2dec('0078');   % Disable the P2 SCLK0 sample clock
                    %                 end
                    
                    % Ammar change the code here for P2 interface
                    %##############################################
                    if( axis == (app.numberOfAxes-1) )
                        app.vmeBusDataU16 = hex2dec('0E00');
                    else
                        app.vmeBusDataU16 = hex2dec('0600');   % Disable the P2 SCLK0 sample clock
                    end
                    %
                    %                  if( axis == (app.numberOfAxes-1) )
                    %                     app.vmeBusDataU16 = hex2dec('0978');
                    %                 else
                    %                     app.vmeBusDataU16 = hex2dec('0578');   % Disable the P2 SCLK0 sample clock
                    %                 end
                    %#############################################
                    if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    
                    % control register 2, position direction sense, Kp, and Kv
                    %                 app.posDirectionSense(axis) = 0;
                    %                 app.filterKP(axis) = 4;
                    %                 app.filterKV(axis) = 4;
                    app.vmeBusOffsetAddr = app.VME_CONTROL_02_REG + app.zygoAxisOffsetAddr(axis);
                    app.vmeBusDataU16    = hex2dec('0400') + hex2dec('4000') * app.posDirectionSense(axis) + (app.filterKP(axis) * 16) +  app.filterKV(axis);
                    
                    if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    
                    % position high nibble
                    app.vmeBusOffsetAddr = app.VME_HIGH_NIBBLES_REG + app.zygoAxisOffsetAddr(axis);
                    app.vmeBusDataU16    = 0;
                    if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    
                    % offset (32 bits write)
                    app.vmeBusOffsetAddr = app.VME_OFFSET_MSB_REG + app.zygoAxisOffsetAddr(axis);
                    app.vmeBusDataU32    = 0;
                    if(RegisterWrite32BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    
                    % data age adjust
                    app.vmeBusOffsetAddr = app.VME_DATA_AGE_ADJUST_REG + app.zygoAxisOffsetAddr(axis);
                    app.vmeBusDataU16    = app.dataAge(axis);
                    if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    
                    % disable P2 interrupt
                    app.vmeBusOffsetAddr = app.VME_P2_INTERRUPT_MASK_REG + app.zygoAxisOffsetAddr(axis);
                    app.vmeBusDataU16    = 0;
                    if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    
                end
                
                % sample timer, rounded, write to base (first) channel of a measurement board
                app.vmeDeviceNumber  = app.zygoAxisDeviceNumber( app.numberOfAxes );
                app.vmeBusOffsetAddr = app.VME_SAMPLE_TIMER_REG + app.zygoAxisOffsetAddr( app.numberOfAxes );
                % period (sec)      = (n + 1) * 50 nsec  for 1 <= n <= 65535
                % sampleTimeCount,n = (unsigned int)(app.samplePeriod / 0.000000050 - 1.0  + 0.5);
                app.vmeBusDataU16   = cast((app.samplePeriod / 0.000000050 - 0.5), 'uint16');
                if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                
                % "axis reset" for all axes
                for(axis = 1:app.numberOfAxes)
                    
                    app.vmeDeviceNumber  = app.zygoAxisDeviceNumber(axis);
                    app.vmeBusOffsetAddr = app.VME_COMMAND_REG + app.zygoAxisOffsetAddr(axis);
                    app.vmeBusDataU16    = hex2dec('0001');	% axis reset
                    if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                end
                
                % wait for last reset complete
                app.vmeDeviceNumber  = app.zygoAxisDeviceNumber( app.numberOfAxes );
                app.vmeBusOffsetAddr = app.VME_STATUS_REG + app.zygoAxisOffsetAddr( app.numberOfAxes );
                loopCount = 0;
                statusUInt = hex2dec('0000');
                while(bitand(statusUInt , hex2dec('0010'))==0)
                    
                    % get position reset complete status
                    if(RegisterRead16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    
                    statusUInt =bitand( app.vmeBusDataU16 , hex2dec('0010'));
                    if( loopCount >=  app.ZYGO_LOOP_CTRL_COUNT_MAX )
                        
                        % app.systemErrorCode = 51;
                        % strcpy(app.systemMessage,"Zygo cannot reset the last measurement board.");
                        printmsg(app,'Error: Zygo cannot reset the last measurement board!');
                        zygo_fnc_out=app.ZYGO_ERROR; return
                    end
                    loopCount=loopCount+1;
                    pause(0.002);
                end
                
                % enable VME interrupts, used only for errors
                for(axis = 1: app.numberOfAxes)
                    app.vmeDeviceNumber  = app.zygoAxisDeviceNumber(axis);
                    app.vmeBusOffsetAddr = app.VME_INTERRUPT_ENABLE_REG + app.zygoAxisOffsetAddr(axis);
                    app.vmeBusDataU16    = hex2dec('07FF');	% axis reset
                    if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                end
                
                % initialise variables
                for(axis = 1:8)
                    app.timeInt(axis)=0;
                    app.timeIntPrev			(axis) = 0;
                    app.timeIndex   			(axis) = 0;
                    app.timeFirstInt			(axis) = 0;
                    app.selectedAxes			(axis) = 0;
                    app.selectedFreq			(axis) = 1;
                    app.flagMeasurementNoSignal(axis) = 1;
                    app.flagReferenceNoSignal 	(axis) = 1;
                    app.flagMeasurementError  	(axis) = 1;
                    app.flagReferenceError	  	(axis) = 1;
                    app.flagOverFlowError	 	(axis) = 1;
                    app.flagVelocityError	  	(axis) = 1;
                end
                
                
                app.retrospacingCompact2AxesHSPMIMm = app.ZYGO_RETROSPACING_COMPACT_2_AXES_HSPMI;
                app.wavelengthCompensatorLengthMm	 = app.ZYGO_WAVELENGTH_COMPENSATOR_LENGTH;
                
                % calculate conversion factors
                ZygoCalculateConversionFactors(app);
                zygo_fnc_out=app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        function zygo_fnc_out= ZygoAxisReset(app)
            try
                % "axis reset" for all axes
                for(axis = 1: app.numberOfAxes)
                    app.vmeDeviceNumber  =app.zygoAxisDeviceNumber(axis);
                    app.vmeBusOffsetAddr = app.VME_COMMAND_REG +app.zygoAxisOffsetAddr(axis);
                    app.vmeBusDataU16    = hex2dec('0001');	% axis reset
                    if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                end
                
                % wait for last reset complete
                app.vmeDeviceNumber  =app.zygoAxisDeviceNumber(app.numberOfAxes );
                app.vmeBusOffsetAddr = app.VME_STATUS_REG +app.zygoAxisOffsetAddr(app.numberOfAxes );
                loopCount = 0;
                statusUInt = hex2dec('0000');
                while(bitand(statusUInt , hex2dec('0010'))==0)
                    % get position reset complete status
                    if(RegisterRead16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    statusUInt =bitand(app.vmeBusDataU16 , hex2dec('0010'));
                    if( loopCount >=  app.ZYGO_LOOP_CTRL_COUNT_MAX )
                        printmsg(app,'Error: Zygo cannot reset the last measurement board');
                        zygo_fnc_out=app.ZYGO_ERROR; return;
                    end
                    loopCount=loopCount+1;
                    pause(0.002);	% 2 ms delay
                end
                % reset time variables
                for(axis = 1: axis <=app.numberOfAxes)
                    app.timeInt	 (axis) = 0;
                    app.timeIntPrev (axis) = 0;
                    app.timeIndex   (axis) = 0;
                    app.timeFirstInt(axis) = 0;
                end
                zygo_fnc_out=app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        function zygo_fnc_out=  ZygoUpdateControlRegisterTwo(app)
            try
                
                % control register 2 of all axes
                for(axis = 1: app.numberOfAxes)
                    
                    app.vmeDeviceNumber  = app.zygoAxisDeviceNumber(axis);
                    app.vmeBusOffsetAddr = app.VME_CONTROL_02_REG + app.zygoAxisOffsetAddr(axis);
                    if(RegisterRead16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    
                    % mask out position direction sense, Kp, and Kv from the register data
                    regUInt = bitand(app.vmeBusDataU16 , hex2dec('BF00'));  % 1011 1111 0000 0000
                    
                    % limit position direction sense to 1 bit, and limit Kp and Kv to only 3 bits for each of their values
                    app.posDirectionSense(axis) = bitand(app.posDirectionSense(axis) , hex2dec('0001'));
                    app.filterKP(axis) = bitand(app.filterKP(axis) , hex2dec('0007'));
                    app.filterKV(axis) = bitand(app.filterKV(axis) , hex2dec('0007'));
                    
                    % combine Kp and Kv in the register data
                    app.vmeBusDataU16 = regUInt + hex2dec('4000') * app.posDirectionSense(axis) + (app.filterKP(axis) * 16) +  app.filterKV(axis);
                    
                    if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                end
                
                zygo_fnc_out=app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        function zygo_fnc_out=ZygoReadSampledPositionVelocityTime(app,axis)
            try
                % sampled position, 32 bits read
                app.vmeDeviceNumber  = app.zygoAxisDeviceNumber(axis);
                %             app.vmeBusOffsetAddr = VME_SAMPLED_POSITION_MSB_REG + app.zygoAxisOffsetAddr(axis);
                app.vmeBusOffsetAddr = app.VME_POSITION_MSB_REG + app.zygoAxisOffsetAddr(axis);


                if (RegisterRead32BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                app.positionInt(axis) = (app.vmeBusDataU32);
                
                % velocity, 32 bits raed
                app.vmeBusOffsetAddr = app.VME_VELOCITY_MSB_REG + app.zygoAxisOffsetAddr(axis);
                if(RegisterRead32BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                % app.velocity(axis) = (double)app.vmeBusData * ZYGO_POSITION_COUNT_TO_NANOMETRE;
                app.velocityInt(axis) = (app.vmeBusDataU32);
                
                % time, 32 bits read
                app.vmeBusOffsetAddr = app.VME_TIME_MSB_REG + app.zygoAxisOffsetAddr(axis);
                if(RegisterRead32BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                % app.time(axis) = (double)app.vmeBusData * ZYGO_TIME_COUNT_TO_SECOND; % 1 count = 25 nsec
                app.timeInt(axis) = (app.vmeBusDataU32);
                zygo_fnc_out= app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        function zygo_fnc_out=ZygoForceExternalSample(app)
            try
                
                % get the base (first axis) address of the last measurement board
                axis =app.numberOfAxes-1;
                % write to command register to make a force external sample
                app.vmeDeviceNumber  =app.zygoAxisDeviceNumber(axis);
                app.vmeBusOffsetAddr = app.VME_COMMAND_REG +app.zygoAxisOffsetAddr(axis);
                app.vmeBusDataU16    =hex2dec('0100');	% force external sample
                if(RegisterWrite16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                
                % wait for the external sample flag
                
                app.vmeBusOffsetAddr = app.VME_STATUS_REG +app.zygoAxisOffsetAddr(axis);
                statusUInt = hex2dec('0000');
                loopCount=0;
                while (bitand(statusUInt , hex2dec('0100'))==0)
                    % get the status of external sample flag
                    if(RegisterRead16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    statusUInt =bitand( app.vmeBusDataU16 , hex2dec('0100'));
                    if( loopCount >=  app.ZYGO_LOOP_CTRL_COUNT_MAX )
                        % app.systemErrorCode = 51;
                        % strcpy(app.systemMessage,"Zygo cannot reset the last measurement board.");
                        printmsg(app,'Error: Zygo cannot reset the last measurement board!');
                        zygo_fnc_out=app.ZYGO_ERROR; return
                    end
                    %                 pause(0.002);
                    loopCount=loopCount+1;
                end
                
                zygo_fnc_out=app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        function zygo_fnc_out=ZygoReadErrorStatusRegister(app)
            try
                for(axis = 1: app.numberOfAxes)
                    % read error status register
                    app.vmeDeviceNumber  =app.zygoAxisDeviceNumber(axis);
                    app.vmeBusOffsetAddr = app.VME_ERROR_STATUS_REG +app.zygoAxisOffsetAddr(axis);
                    if(RegisterRead16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    app.errorStatusReg(axis) =app.vmeBusDataU16;
                    
                    % set flags
                    % measurement error: measurement signal missing, measurement signal dropout, or measurement signal glitch
                    app.flagMeasurementError(axis) = 0;
                    if(bitand(app.errorStatusReg(axis) , hex2dec('0008')) || bitand(app.errorStatusReg(axis) , hex2dec('0010')) || bitand(app.errorStatusReg(axis), hex2dec('0020')))
                        app.flagMeasurementError(axis) = 1;
                    end
                    
                    % reference error: reference signal missing, or reference PLL error
                    app.flagReferenceError(axis) = 0;
                    if(bitand(app.errorStatusReg(axis) , hex2dec('0001')) || bitand(app.errorStatusReg(axis) , hex2dec('0002')))
                        app.flagReferenceError(axis) = 1;
                    end
                    
                    % over flow error: 32-bit or 36-bit position overflow error
                    app.flagOverFlowError(axis) = 0;
                    if(bitand(app.errorStatusReg(axis) , hex2dec('0400')) || bitand(app.errorStatusReg(axis) , hex2dec('0200')))
                        app.flagOverFlowError(axis) = 1;
                    end
                    
                    % velocity error: velocity error, user velocity error, or acceleration error
                    app.flagVelocityError(axis) = 0;
                    if(bitand(app.errorStatusReg(axis) , hex2dec('0040')) || bitand(app.errorStatusReg(axis) , hex2dec('0080')) || bitand(app.errorStatusReg(axis) , hex2dec('0100')))
                        app.flagVelocityError(axis) = 1;
                    end
                end
                zygo_fnc_out=app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        function zygo_fnc_out=ZygoReadStatusRegister(app)
            try
                for(axis = 1: app.numberOfAxes)
                    % read status register
                    app.vmeDeviceNumber  =app.zygoAxisDeviceNumber(axis);
                    app.vmeBusOffsetAddr = app.VME_STATUS_REG +app.zygoAxisOffsetAddr(axis);
                    if(RegisterRead16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
                    app.statusRegister(axis) =app.vmeBusDataU16;
                    % set flags
                    % measurement signal from interferometer present (no signal = 1)
                    app.flagMeasurementNoSignal(axis) = 1;
                    if(bitand(app.statusRegister(axis) , hex2dec('0008')))
                        app.flagMeasurementNoSignal(axis) = 0;
                    end
                    
                    % reference signal from laser head present (no signal = 1)
                    app.flagReferenceNoSignal(axis) = 1;
                    if(bitand(app.statusRegister(axis) , hex2dec('0001')))
                        app.flagReferenceNoSignal(axis) = 0;
                    end
                end
                zygo_fnc_out=app.ZYGO_OK;
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        
        function zygo_fnc_out=ZygoCalculateConversionFactors(app)
            try
                
                % change of variables
                temp =app.temperatureDegC;
                pres =app.pressureMmHg;
                humi =app.relativeHumidityPercent;
                
                % water vapor pressure of saturated air
                pressureSatAir = 4.07859739 + 0.44301857 * temp + 0.00232093 * temp^2 + 0.00045785 * temp^3;
                
                % partial pressure of the water vapor in the air (mm Hg)
                partialPressure = ( humi * pressureSatAir ) / 100.00;
                
                % initial value of the refractive index is calculated by using Edlen's general formula
                app.initialRefractiveIndex = 1 + 3.83639 * 10^-7 * pres * ( 1 + pres * ( 0.817 - 0.0133 * temp ) * 10^-6 ) / ( 1 + 0.003661 * temp ) - 5.607943 *  10^-8 * partialPressure;
                
                % refractive index of air (relative)
                %app.localRefractiveIndex =app.initialRefractiveIndex; % +/- N * lambda_vac / ( 2048 * L)
                
                % determine wavelength in air
                app.initialLambda_F1 = app.ZYGO_LAMBDA_F1_VACUUM_VERTICAL   /app.initialRefractiveIndex;
                app.initialLambda_F2 = app.ZYGO_LAMBDA_F2_VACUUM_HORIZONTAL /app.initialRefractiveIndex;
                
                % psotion conversion factors: count to nano-metre
                app.positionF1Count2Nm =app.initialLambda_F1 / 2048;  % for two-pass interferometer
                app.positionF2Count2Nm =app.initialLambda_F2 / 2048;  % for two-pass interferometer
                
                % velocity conversion factors: count to nano-metre / sec
                app.velocityF1Count2NmPerSec = 40 * 10^6 *app.initialLambda_F1 / 2^34;
                app.velocityF2Count2NmPerSec = 40 * 10^6 *app.initialLambda_F2 / 2^34;
                
                % for compact 2-axis high stability plane mirror interferometer
                % tangent angle conversion factors: count to radians
                % note: angle = arctangent( N * conversion factor) = atan( N * conversion factor)  (rad)
                app.angleF1Count2TanQ =app.initialLambda_F1 / ( 2048 *app.retrospacingCompact2AxesHSPMIMm * 10^6 );
                app.angleF2Count2TanQ =app.initialLambda_F2 / ( 2048 *app.retrospacingCompact2AxesHSPMIMm * 10^6 );
                
                % for compact 2-axis high stability plane mirror interferometer
                % angular velocity
                app.angVelF1Count2TanQPerSec = 40 * 10^6 *app.initialLambda_F1 / ( 2^34 * app.retrospacingCompact2AxesHSPMIMm * 10^6 );
                
                app.angVelF2Count2TanQPerSec = 40 * 10^6 *app.initialLambda_F2 / (2^34* app.retrospacingCompact2AxesHSPMIMm * 10^6 );
            catch ME
                printmsg(app,ME.message)
                zygo_fnc_out=app.ZYGO_ERROR;
            end
        end
        %% Zygo system functions
        function zygo_fnc_out= Initialise_system(app)
            
            
            checkFunctionCall = ZygoInitialize(app);
            if(checkFunctionCall ~= app.ZYGO_OK)
                printmsg (app,'Error Zygo Measurment System cannot be initialised!!!');
                zygo_fnc_out=app.ZYGO_ERROR;return
            end
            
            
            checkFunctionCall = ZygoGetDataAge(app);
            if(checkFunctionCall ~= app.ZYGO_OK)
                printmsg (app,'Error System fails to get Zygos Data Age!');
                zygo_fnc_out=app.ZYGO_ERROR;return
            end
            
            checkFunctionCall = ZygoRegisterInitialiseAxisReset(app);
            if(checkFunctionCall ~= app.ZYGO_OK)
                printmsg (app,'Error System fails to reset Zygos Axes!');
                zygo_fnc_out=app.ZYGO_ERROR;return
            end
            ZygoForceExternalSample(app)
            
            % initialise error flags
            % read error status register and status register
            checkFunctionCall = ZygoReadErrorStatusRegister(app);
            if(checkFunctionCall ~= app.ZYGO_OK)
                printmsg (app,'Error System fails to read Zygos Error Status Register!');
                zygo_fnc_out=app.ZYGO_ERROR;return
            end
            
            checkFunctionCall = ZygoReadStatusRegister(app);
            if(checkFunctionCall ~= app.ZYGO_OK)
                printmsg (app,'Error System fails to read Zygos Status Register!');
                zygo_fnc_out=app.ZYGO_ERROR;return
            end
            % testing of EEPROM read
            app.axisNumber = 1;
            app.eepromDataAddr = hex2dec('01');
            checkFunctionCall = ZygoEepromReadData(app);
            if(checkFunctionCall ~= app.ZYGO_OK)
                printmsg (app,'Error System fails to read Zygos EEPROM!');
                zygo_fnc_out=app.ZYGO_ERROR;return
            end
            
            printmsg(app,'Zygo ZMI 2400 Laser Measurement System is initialised');
        end
        
        function  zygo_fnc_out= Quit_system(app)
            checkFunctionCall = 	ZygoOffline(app);
            if(checkFunctionCall ~= app.ZYGO_OK)
                printmsg (app,'Error System fails to Quit!');
                zygo_fnc_out=app.ZYGO_ERROR;return
            end
            printmsg (app,'System Quit successfully');
        end
        
        function  zygo_fnc_out=  AxisReset_system(app)
            checkFunctionCall = ZygoAxisReset(app);
            if(checkFunctionCall ~= app.ZYGO_OK)
                printmsg (app,'Error System fails to reset Zygos Axes!');
                zygo_fnc_out=app.ZYGO_ERROR;return
            end
            printmsg (app,' Axis Reset successfully');
            
        end
        
        function  zygo_fnc_out=  EnvDefault_system(app)
            %default values for environmental variables
            app.temperatureDegC 		 		 = app.ZYGO_DEFAULT_TEMPERATURE;
            app.pressureMmHg					 = app.ZYGO_DEFAULT_PRESSURE;
            app.relativeHumidityPercent		 = app.ZYGO_DEFAULT_RELATIVE_HUMIDITY;
            app.retrospacingCompact2AxesHSPMIMm = app.ZYGO_RETROSPACING_COMPACT_2_AXES_HSPMI;
            app.wavelengthCompensatorLengthMm	 = app.ZYGO_WAVELENGTH_COMPENSATOR_LENGTH;
            %calculate conversion factors
            ZygoCalculateConversionFactors(app);
            zygo_fnc_out=app.ZYGO_OK;
        end
        function zygo_fnc_out= ZygoReadVmeRegister(app)
            axis = app.axisNumber;
            %read a vme register or a offset location
            app.vmeDeviceNumber  = app.zygoAxisDeviceNumber(axis);
            app.vmeBusOffsetAddr =app.vmeBusRegisterAddr + app.zygoAxisOffsetAddr(axis);
            if(RegisterRead16BitData(app) ~= app.ZYGO_OK); zygo_fnc_out=app.ZYGO_ERROR; return;end
            app.vmeBusRegisterDataU16 = app.vmeBusDataU16;
            zygo_fnc_out= app.ZYGO_OK;
        end
        
        function zygo_fnc_out= Print_status(app)
            printmsg(app,'=============================')
            printmsg(app,['Data Age Adjust=' num2str(app.dataAgeAdjust)])
            
            for(axis = 1: app.numberOfAxes)
                printmsg(app,['Axis ( ', num2str(axis),' )'])
                printmsg(app,'=============================')
                if (app.flagMeasurementError(axis) == 1)
                    printmsg(app,'Measurement Error')
                else
                    printmsg(app,'Measurement OK')
                end
                
                if (app.flagReferenceError(axis) == 1)
                    printmsg(app,'Reference Error')
                else
                    printmsg(app,'Reference OK')
                end
                
                if (app.flagOverFlowError(axis)==1)
                    
                    printmsg(app,'flag OverFlow Error')
                else
                    printmsg(app,'flag OverFlow OK')
                end
                
                if (app.flagVelocityError(axis)==1)
                    
                    printmsg(app,'Velocity Error')
                else
                    printmsg(app,'Velocity OK')
                end
                if (app.flagMeasurementNoSignal(axis)==1)
                    
                    printmsg(app,'Measurement No Signal Error')
                else
                    printmsg(app,'Measurement No Signal OK')
                end
                if (app.flagReferenceNoSignal(axis)==1)
                    
                    printmsg(app,'Reference No Signal Error')
                else
                    printmsg(app,'Reference No Signal OK')
                end
                
                printmsg(app,['data age =' num2str(app.dataAge(axis))])
                
                app.axisNumber=axis;
                app.vmeBusRegisterAddr=app.VME_CONTROL_01_REG;
                ZygoReadVmeRegister(app);
                printmsg(app,['control reg1=' num2str(dec2bin(app.vmeBusRegisterDataU16))])
                
                app.vmeBusRegisterAddr=app.VME_CONTROL_02_REG;
                ZygoReadVmeRegister(app);
                printmsg(app,['control reg2=' num2str(dec2bin(app.vmeBusRegisterDataU16))])
                
                printmsg(app,['STATUS reg=' num2str(dec2bin(app.statusRegister(axis)))])
                
                printmsg(app,['ERROR_STATUS_REG=' num2str(dec2bin( app.errorStatusReg(axis)))])
                printmsg(app,'=============================')
            end
            zygo_fnc_out= app.ZYGO_OK;
        end
        
        function zygo_fnc_out= check_status(app,axis)
            
            if (app.flagMeasurementError(axis) == 1) zygo_fnc_out= app.ZYGO_ERROR;return;end
            if (app.flagReferenceError(axis) == 1) zygo_fnc_out= app.ZYGO_ERROR;return;end
            if (app.flagOverFlowError(axis)==1) zygo_fnc_out= app.ZYGO_ERROR;return;end
            if (app.flagVelocityError(axis)==1) zygo_fnc_out= app.ZYGO_ERROR;return;end
            if (app.flagMeasurementNoSignal(axis)==1) zygo_fnc_out= app.ZYGO_ERROR;return;end
            if (app.flagReferenceNoSignal(axis)==1) zygo_fnc_out= app.ZYGO_ERROR;return;end
            zygo_fnc_out= app.ZYGO_OK;
        end
        
        function printmsg(app,msg)
            aa= app.ListBox.Items;
            bb=app.ListBox.ItemsData;
            last=numel(aa);
            aa{last+1}=msg;
            bb(last+1)=last+1;
            app.ListBox.Items=aa;
            app.ListBox.ItemsData=bb;
            scroll(app.ListBox,last+1);
        end
        
        function results = checkStatusTimerCallbackFunction(app)
            try
             nsamples  =app.NumberofSamplesKnob.Value;
            for i=1:app.numberOfAxes
                ZygoReadErrorStatusRegister(app);
                ZygoReadStatusRegister(app);
                
                if i==1
                    if (app.flagMeasurementError(1) == 1)
                        app.LedME.Color = [1 0 0];
                    else
                        app.LedME.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceError(1) == 1)
                        app.LedRE.Color = [1 0 0];
                    else
                        app.LedRE.Color = [0 1 0];
                    end
                    
                    if (app.flagOverFlowError(1)==1)
                        app.LedOFE.Color = [1 0 0];
                    else
                        app.LedOFE.Color = [0 1 0];
                    end
                    
                    if (app.flagVelocityError(1)==1)
                        app.LedVE.Color = [1 0 0];
                    else
                        app.LedVE.Color = [0 1 0];
                    end
                    
                    if (app.flagMeasurementNoSignal(1)==1)
                        app.LedMSE.Color = [1 0 0];
                    else
                        app.LedMSE.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceNoSignal(1)==1)
                        app.LedRSE.Color = [1 0 0];
                    else
                        app.LedRSE.Color = [0 1 0];
                    end
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if i==2
                    if (app.flagMeasurementError(2) == 1)
                        app.LedME_2.Color = [1 0 0];
                    else
                        app.LedME_2.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceError(2) == 1)
                        app.LedRE_2.Color = [1 0 0];
                    else
                        app.LedRE_2.Color = [0 1 0];
                    end
                    
                    if (app.flagOverFlowError(2)==1)
                        app.LedOFE_2.Color = [1 0 0];
                    else
                        app.LedOFE_2.Color = [0 1 0];
                    end
                    
                    if (app.flagVelocityError(2)==1)
                        app.LedVE_2.Color = [1 0 0];
                    else
                        app.LedVE_2.Color = [0 1 0];
                    end
                    
                    if (app.flagMeasurementNoSignal(2)==1)
                        app.LedMSE_2.Color = [1 0 0];
                    else
                        app.LedMSE_2.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceNoSignal(2)==1)
                        app.LedRSE_2.Color = [1 0 0];
                    else
                        app.LedRSE_2.Color = [0 1 0];
                    end
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if i==3
                    if (app.flagMeasurementError(3) == 1)
                        app.LedME_3.Color = [1 0 0];
                    else
                        app.LedME_3.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceError(3) == 1)
                        app.LedRE_3.Color = [1 0 0];
                    else
                        app.LedRE_3.Color = [0 1 0];
                    end
                    
                    if (app.flagOverFlowError(3)==1)
                        app.LedOFE_3.Color = [1 0 0];
                    else
                        app.LedOFE_3.Color = [0 1 0];
                    end
                    
                    if (app.flagVelocityError(3)==1)
                        app.LedVE_3.Color = [1 0 0];
                    else
                        app.LedVE_3.Color = [0 1 0];
                    end
                    
                    if (app.flagMeasurementNoSignal(3)==1)
                        app.LedMSE_3.Color = [1 0 0];
                    else
                        app.LedMSE_3.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceNoSignal(3)==1)
                        app.LedRSE_3.Color = [1 0 0];
                    else
                        app.LedRSE_3.Color = [0 1 0];
                    end
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if i==4
                    if (app.flagMeasurementError(4) == 1)
                        app.LedME_4.Color = [1 0 0];
                    else
                        app.LedME_4.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceError(4) == 1)
                        app.LedRE_4.Color = [1 0 0];
                    else
                        app.LedRE_4.Color = [0 1 0];
                    end
                    
                    if (app.flagOverFlowError(4)==1)
                        app.LedOFE_4.Color = [1 0 0];
                    else
                        app.LedOFE_4.Color = [0 1 0];
                    end
                    
                    if (app.flagVelocityError(4)==1)
                        app.LedVE_4.Color = [1 0 0];
                    else
                        app.LedVE_4.Color = [0 1 0];
                    end
                    
                    if (app.flagMeasurementNoSignal(4)==1)
                        app.LedMSE_4.Color = [1 0 0];
                    else
                        app.LedMSE_4.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceNoSignal(4)==1)
                        app.LedRSE_4.Color = [1 0 0];
                    else
                        app.LedRSE_4.Color = [0 1 0];
                    end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if i==5
                    if (app.flagMeasurementError(5) == 1)
                        app.LedME_5.Color = [1 0 0];
                    else
                        app.LedME_5.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceError(5) == 1)
                        app.LedRE_5.Color = [1 0 0];
                    else
                        app.LedRE_5.Color = [0 1 0];
                    end
                    
                    if (app.flagOverFlowError(5)==1)
                        app.LedOFE_5.Color = [1 0 0];
                    else
                        app.LedOFE_5.Color = [0 1 0];
                    end
                    
                    if (app.flagVelocityError(5)==1)
                        app.LedVE_5.Color = [1 0 0];
                    else
                        app.LedVE_5.Color = [0 1 0];
                    end
                    
                    if (app.flagMeasurementNoSignal(5)==1)
                        app.LedMSE_5.Color = [1 0 0];
                    else
                        app.LedMSE_5.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceNoSignal(5)==1)
                        app.LedRSE_5.Color = [1 0 0];
                    else
                        app.LedRSE_5.Color = [0 1 0];
                    end
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if i==6
                    if (app.flagMeasurementError(6) == 1)
                        app.LedME_6.Color = [1 0 0];
                    else
                        app.LedME_6.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceError(6) == 1)
                        app.LedRE_6.Color = [1 0 0];
                    else
                        app.LedRE_6.Color = [0 1 0];
                    end
                    
                    if (app.flagOverFlowError(6)==1)
                        app.LedOFE_6.Color = [1 0 0];
                    else
                        app.LedOFE_6.Color = [0 1 0];
                    end
                    
                    if (app.flagVelocityError(6)==1)
                        app.LedVE_6.Color = [1 0 0];
                    else
                        app.LedVE_6.Color = [0 1 0];
                    end
                    
                    if (app.flagMeasurementNoSignal(6)==1)
                        app.LedMSE_6.Color = [1 0 0];
                    else
                        app.LedMSE_6.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceNoSignal(6)==1)
                        app.LedRSE_6.Color = [1 0 0];
                    else
                        app.LedRSE_6.Color = [0 1 0];
                    end
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if i==7
                    if (app.flagMeasurementError(7) == 1)
                        app.LedME_7.Color = [1 0 0];
                    else
                        app.LedME_7.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceError(7) == 1)
                        app.LedRE_7.Color = [1 0 0];
                    else
                        app.LedRE_7.Color = [0 1 0];
                    end
                    
                    if (app.flagOverFlowError(7)==1)
                        app.LedOFE_7.Color = [1 0 0];
                    else
                        app.LedOFE_7.Color = [0 1 0];
                    end
                    
                    if (app.flagVelocityError(7)==1)
                        app.LedVE_7.Color = [1 0 0];
                    else
                        app.LedVE_7.Color = [0 1 0];
                    end
                    
                    if (app.flagMeasurementNoSignal(7)==1)
                        app.LedMSE_7.Color = [1 0 0];
                    else
                        app.LedMSE_7.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceNoSignal(7)==1)
                        app.LedRSE_7.Color = [1 0 0];
                    else
                        app.LedRSE_7.Color = [0 1 0];
                    end
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if i==8
                    if (app.flagMeasurementError(8) == 1)
                        app.LedME_8.Color = [1 0 0];
                    else
                        app.LedME_8.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceError(8) == 1)
                        app.LedRE_8.Color = [1 0 0];
                    else
                        app.LedRE_8.Color = [0 1 0];
                    end
                    
                    if (app.flagOverFlowError(8)==1)
                        app.LedOFE_8.Color = [1 0 0];
                    else
                        app.LedOFE_8.Color = [0 1 0];
                    end
                    
                    if (app.flagVelocityError(8)==1)
                        app.LedVE_8.Color = [1 0 0];
                    else
                        app.LedVE_8.Color = [0 1 0];
                    end
                    
                    if (app.flagMeasurementNoSignal(8)==1)
                        app.LedMSE_8.Color = [1 0 0];
                    else
                        app.LedMSE_8.Color = [0 1 0];
                    end
                    
                    if (app.flagReferenceNoSignal(8)==1)
                        app.LedRSE_8.Color = [1 0 0];
                    else
                        app.LedRSE_8.Color = [0 1 0];
                    end
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            end
        
 knobvalue=app.RangeKnob.Value;
 
if knobvalue==1
conv_factor=app.positionF1Count2Nm;
end
if knobvalue==2
conv_factor=app.positionF1Count2Nm*0.001;
end
if knobvalue==3
conv_factor=app.positionF1Count2Nm*0.000001;
end
if knobvalue==4
conv_factor=app.positionF1Count2Nm*0.0000001;
end
if knobvalue==5
conv_factor=app.positionF1Count2Nm*0.000000001;
end
if knobvalue==6
conv_factor=1;
end

app.ZygoForceExternalSample();
if (app.Axis1Switch.Value==1) && (app.numberOfAxes>=1)
    ZygoReadSampledPositionVelocityTime(app,1);
    app.Axis1positionEditField.Value=app.positionInt(1)*conv_factor;
    app.Axis1velocityEditField.Value=app.velocityInt(1)*conv_factor;
    if app.PlotPositionsSwitch.Value==1
        app.positionrecored(1,1:nsamples-1)=app.positionrecored(1,2:nsamples);
        app.positionrecored(1,nsamples)=app.positionInt(1)*conv_factor;
%         figure(2)
%         plot(app.positionrecored(1,:))
set(app.ph1,'XData',[1:nsamples]);
        set(app.ph1,'YData',app.positionrecored(1,:));
drawnow;
    end
end

if app.Axis2Switch.Value==1 && app.numberOfAxes>=2
    ZygoReadSampledPositionVelocityTime(app,2);
    app.Axis2positionEditField.Value=app.positionInt(2)*conv_factor;% * app.positionF1Count2Nm;
    app.Axis2velocityEditField.Value=app.velocityInt(2)*conv_factor;
    if app.PlotPositionsSwitch.Value==1
        app.positionrecored(2,1:nsamples-1)=app.positionrecored(2,2:nsamples);
        app.positionrecored(2,nsamples)=app.positionInt(2)*conv_factor;
%         figure(3)
%         plot(app.positionrecored(2,:)) 
 set(app.ph2,'XData',[1:nsamples]);
 set(app.ph2,'YData',app.positionrecored(2,:));
     drawnow;

 
    end
end

if app.Axis3Switch.Value==1 && app.numberOfAxes>=3
    ZygoReadSampledPositionVelocityTime(app,3);
    app.Axis3positionEditField.Value=app.positionInt(3)*conv_factor;% * app.positionF1Count3Nm;
    app.Axis3velocityEditField.Value=app.velocityInt(3)*conv_factor;
    if app.PlotPositionsSwitch.Value==1
        app.positionrecored(3,1:nsamples-1)=app.positionrecored(3,2:nsamples);
        app.positionrecored(3,nsamples)=app.positionInt(3)*conv_factor;
        figure(4)
        plot(app.positionrecored(3,:)) 
    end
end

if app.Axis4Switch.Value==1 && app.numberOfAxes>=4
    ZygoReadSampledPositionVelocityTime(app,4);
    app.Axis4positionEditField.Value=app.positionInt(4)*conv_factor;% * app.positionF1Count4Nm;
    app.Axis4velocityEditField.Value=app.velocityInt(4)*conv_factor;
    if app.PlotPositionsSwitch.Value==1
        app.positionrecored(4,1:nsamples-1)=app.positionrecored(4,2:nsamples);
        app.positionrecored(4,nsamples)=app.positionInt(4)*conv_factor;
        figure(5)
        plot(app.positionrecored(4,:)) 
    end
end

if app.Axis5Switch.Value==1 && app.numberOfAxes>=5
    ZygoReadSampledPositionVelocityTime(app,5);
    app.Axis5positionEditField.Value=app.positionInt(5)*conv_factor;% * app.positionF1Count5Nm;
    app.Axis5velocityEditField.Value=app.velocityInt(5)*conv_factor;
    if app.PlotPositionsSwitch.Value==1
        app.positionrecored(5,1:nsamples-1)=app.positionrecored(5,2:nsamples);
        app.positionrecored(5,nsamples)=app.positionInt(5)*conv_factor;
        figure(6)
        plot(app.positionrecored(5,:))
    end
end


if app.Axis6Switch.Value==1 && app.numberOfAxes>=6
    ZygoReadSampledPositionVelocityTime(app,6);
    app.Axis6positionEditField.Value=app.positionInt(6)*conv_factor;% * app.positionF1Count6Nm;
    app.Axis6velocityEditField.Value=app.velocityInt(6)*conv_factor;
    if app.PlotPositionsSwitch.Value==1
        app.positionrecored(6,1:nsamples-1)=app.positionrecored(6,2:nsamples);
        app.positionrecored(6,nsamples)=app.positionInt(6)*conv_factor;
        figure(7)
        plot(app.positionrecored(6,:))
    end
end

if app.Axis7Switch.Value==1 && app.numberOfAxes>=7
    ZygoReadSampledPositionVelocityTime(app,7);
    app.Axis7positionEditField.Value=app.positionInt(7)*conv_factor;% * app.positionF1Count7Nm;
    app.Axis7velocityEditField.Value=app.velocityInt(7)*conv_factor;
    if app.PlotPositionsSwitch.Value==1
        app.positionrecored(7,1:nsamples-1)=app.positionrecored(7,2:nsamples);
        app.positionrecored(7,nsamples)=app.positionInt(7)*conv_factor;
        figure(8)
        plot(app.positionrecored(7,:))
    end
end

if app.Axis8Switch.Value==1 && app.numberOfAxes>=8
    ZygoReadSampledPositionVelocityTime(app,8);
    app.Axis8positionEditField.Value=app.positionInt(8)*conv_factor;% * app.positionF1Count8Nm;
    app.Axis8velocityEditField.Value=app.velocityInt(8)*conv_factor;
    if app.PlotPositionsSwitch.Value==1
        app.positionrecored(8,1:nsamples-1)=app.positionrecored(8,2:nsamples);
        app.positionrecored(8,nsamples)=app.positionInt(8)*conv_factor;
        figure(9)
        plot(app.positionrecored(8,:))
    end
end


catch ME
    app.PlotPositionsSwitch.Value=0;
 printmsg(app,ME.message)
             
end


        end
    end 

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ~isempty(timerfind)
                stop(timerfind)
                delete(timerfind)
            end

                a=instrfind;
                if (isempty(a)==0)
                    fclose(a);
                    pause(3)
                    delete(a);
                    pause(1)
                    clear a;
                    pause(3)
                end
                app.RangeKnob.ItemsData = {1,2,3,4,5,6};
                app.NumberofAxisKnob.ItemsData={2,4,6,8};
                app.NumberofSamplesKnob.ItemsData={10,100,1000,10000};
                app.KpDropDown_1.ItemsData={1,2,3,4,5,6,7};
                app.KpDropDown_2.ItemsData={1,2,3,4,5,6,7};
                app.KpDropDown_3.ItemsData={1,2,3,4,5,6,7};
                app.KpDropDown_4.ItemsData={1,2,3,4,5,6,7};
                app.KpDropDown_5.ItemsData={1,2,3,4,5,6,7};
                app.KpDropDown_6.ItemsData={1,2,3,4,5,6,7};
                app.KpDropDown_7.ItemsData={1,2,3,4,5,6,7};
                app.KpDropDown_8.ItemsData={1,2,3,4,5,6,7};
                
                app.KvDropDown_1.ItemsData={1,2,3,4,5,6,7};
                app.KvDropDown_2.ItemsData={1,2,3,4,5,6,7};
                app.KvDropDown_3.ItemsData={1,2,3,4,5,6,7};
                app.KvDropDown_4.ItemsData={1,2,3,4,5,6,7};
                app.KvDropDown_5.ItemsData={1,2,3,4,5,6,7};
                app.KvDropDown_6.ItemsData={1,2,3,4,5,6,7};
                app.KvDropDown_7.ItemsData={1,2,3,4,5,6,7};
                app.KvDropDown_8.ItemsData={1,2,3,4,5,6,7};
                
                app.DirDropDown_1.ItemsData={0,1};
                app.DirDropDown_2.ItemsData={0,1};
                app.DirDropDown_3.ItemsData={0,1};
                app.DirDropDown_4.ItemsData={0,1};
                app.DirDropDown_5.ItemsData={0,1};
                app.DirDropDown_6.ItemsData={0,1};
                app.DirDropDown_7.ItemsData={0,1};
                app.DirDropDown_8.ItemsData={0,1};
                
                app.Axis1Switch.ItemsData={0,1};
                app.Axis2Switch.ItemsData={0,1};
                app.Axis3Switch.ItemsData={0,1};
                app.Axis4Switch.ItemsData={0,1};
                app.Axis5Switch.ItemsData={0,1};
                app.Axis6Switch.ItemsData={0,1};
                app.Axis7Switch.ItemsData={0,1};
                app.Axis8Switch.ItemsData={0,1};
                app.PlotPositionsSwitch.ItemsData={0,1};
        end

        % Button pushed function: AxisResetButton
        function AxisResetButtonPushed(app, event)
                AxisReset_system(app);
        end

        % Value changed function: HumidityEditField
        function HumidityEditFieldValueChanged(app, event)
                app.HumidityGauge.Value = app.HumidityEditField.Value;
                
        end

        % Button pushed function: InitializeSystemButton
        function InitializeSystemButtonPushed(app, event)
                printmsg(app,'Please wait')
                
                
                app.numberOfAxes=  app.NumberofAxisKnob.Value;
                app.temperatureDegC 		 		 = app.TemperatureEditField.Value;
                app.pressureMmHg					 = app.PressureEditField.Value;
                app.relativeHumidityPercent		 = app.HumidityEditField.Value;
                
                app.posDirectionSense(1) = app.DirDropDown_1.Value;
                app.filterKP(1) = app.KpDropDown_1.Value;
                app.filterKV(1) =app.KvDropDown_1.Value;
                
                app.posDirectionSense(2) = app.DirDropDown_2.Value;
                app.filterKP(2) = app.KpDropDown_2.Value;
                app.filterKV(2) =app.KvDropDown_2.Value;
                
                app.posDirectionSense(3) = app.DirDropDown_3.Value;
                app.filterKP(3) = app.KpDropDown_3.Value;
                app.filterKV(3) =app.KvDropDown_3.Value;
                
                app.posDirectionSense(4) = app.DirDropDown_4.Value;
                app.filterKP(4) = app.KpDropDown_4.Value;
                app.filterKV(4) =app.KvDropDown_4.Value;
                
                app.posDirectionSense(5) = app.DirDropDown_5.Value;
                app.filterKP(5) = app.KpDropDown_5.Value;
                app.filterKV(5) =app.KvDropDown_5.Value;
                
                app.posDirectionSense(6) = app.DirDropDown_6.Value;
                app.filterKP(6) = app.KpDropDown_6.Value;
                app.filterKV(6) =app.KvDropDown_6.Value;
                
                app.posDirectionSense(7) = app.DirDropDown_7.Value;
                app.filterKP(7) = app.KpDropDown_7.Value;
                app.filterKV(7) =app.KvDropDown_7.Value;
                
                app.posDirectionSense(8) = app.DirDropDown_8.Value;
                app.filterKP(8) = app.KpDropDown_8.Value;
                app.filterKV(8) =app.KvDropDown_8.Value;
                
                nsamples  =app.NumberofSamplesKnob.Value;
                app.positionrecored=zeros(8,nsamples);


                a=instrfind;
                if (isempty(a)==0)
                    fclose(a);
                    pause(1)
                   
                end
                for i=1:floor(app.numberOfAxes/2)
                    if (i==1)
                        app.VMEDevice(1)= visa('ni','VXI0::400::INSTR');
                        fopen(app.VMEDevice(1));
                    end
                    if (i==2)
                        app.VMEDevice(2)= visa('ni','VXI0::405::INSTR');
                        fopen(app.VMEDevice(2));
                    end
                    if (i==3)
                        app.VMEDevice(3)= visa('ni','VXI0::410::INSTR');
                        fopen(app.VMEDevice(3));
                    end
                    if (i==4)
                        app.VMEDevice(4)= visa('ni','VXI0::415::INSTR');
                        fopen(app.VMEDevice(4));
                    end
                    if (i==5)
                        app.VMEDevice(5)= visa('ni','VXI0::420::INSTR');
                        fopen(app.VMEDevice(5));
                    end
                    if (i==6)
                        app.VMEDevice(6)= visa('ni','VXI0::425::INSTR');
                        fopen(app.VMEDevice(6));
                    end
                end
                printmsg(app,'System Setup Paramters')
                printmsg(app,'__________________________')
                printmsg(app,['Number of Axis=' num2str( app.numberOfAxes)])
                printmsg(app,['Temperature=' num2str( app.temperatureDegC)])
                printmsg(app,['Pressure=' num2str( app.pressureMmHg)])
                printmsg(app,['Humidity=' num2str(  app.relativeHumidityPercent)])
                printmsg(app,'__________________________')
                Initialise_system(app);
                Print_status(app);
               
                app.myTimer = timer('Period',0.01,...
                    'ExecutionMode', 'fixedSpacing', ...
                    'TasksToExecute', Inf);
                app.myTimer.TimerFcn = @(obj, event)checkStatusTimerCallbackFunction(app);
                
                start(app.myTimer)


        end

        % Value changed function: PressureEditField
        function PressureEditFieldValueChanged(app, event)
                app.PressureGauge.Value = app.PressureEditField.Value;
                
        end

        % Value changed function: TemperatureEditField
        function TemperatureEditFieldValueChanged(app, event)
                app.TemperatureGauge.Value=app.TemperatureEditField.Value;
        end

        % Callback function
        function startButtonPushed(app, event)
                app.VMEDevice(1)= visa('ni','VXI0::400::INSTR');
                fopen(app.VMEDevice(1));
        end

        % Callback function
        function StartPlottingButtonPushed(app, event)
                i=1;
                %                 for j=1:10000
                %
                %                     app.ZygoForceExternalSample();
                %                     app.ZygoReadSampledPositionVelocityTime(1);
                %
                %                     pos(i)=app.positionInt(1) * app.positionF1Count2Nm;
                %                     i=i+1;
                %                     figure(2)
                %                     plot(pos)
                %                     %                 ylim([-500 500])
                %                     % ZG.velocityInt(axis)* zygo.velocityF1Count2NmPerSec;
                %                     % ZG.timeInt(axis)
                %
                %                 end
        end

        % Button pushed function: ZygoOffButton
        function ZygoOffButtonPushed(app, event)
            printmsg(app,'Zygo Communications is off press "Initialize System" to start');
           zgoff; 
        end

        % Value changed function: PlotPositionsSwitch
        function PlotPositionsSwitchValueChanged(app, event)
            value = app.PlotPositionsSwitch.Value;
            if value==1
                if app.Axis1Switch.Value==1
                 figure(2);
app.ph1 = plot(0,0);
                end
if app.Axis2Switch.Value==1
figure(3);
app.ph2= plot(0,0);
end
            end
            
        end

        % Value changed function: NumberofSamplesKnob
        function NumberofSamplesKnobValueChanged(app, event)
                        nsamples  =app.NumberofSamplesKnob.Value;
                app.positionrecored=zeros(8,nsamples);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZygoLaserMeasurementSystemProgrammedbyAmmarAlJodahUIFigure = uifigure;
            app.ZygoLaserMeasurementSystemProgrammedbyAmmarAlJodahUIFigure.Name = 'Zygo Laser Measurement System Programmed by: Ammar Al-Jodah';
            app.SystemSetupPanel = uipanel(app.ZygoLaserMeasurementSystemProgrammedbyAmmarAlJodahUIFigure);
            app.SystemSetupPanel.Title = 'System Setup';
            app.NumberofAxisKnobLabel = uilabel(app.SystemSetupPanel);
            app.NumberofAxisKnobLabel.Text = 'Number of Axis';
            app.NumberofAxisKnob = uiknob(app.SystemSetupPanel, 'discrete');
            app.NumberofAxisKnob.Items = {'2', '4', '6', '8'};
            app.NumberofAxisKnob.Value = '4';
            app.RangeKnobLabel = uilabel(app.SystemSetupPanel);
            app.RangeKnobLabel.Text = 'Range';
            app.RangeKnob = uiknob(app.SystemSetupPanel, 'discrete');
            app.RangeKnob.Items = {'nm', 'um', 'mm', 'cm', 'm', 'count'};
            app.RangeKnob.Value = 'nm';
            app.InitializeSystemButton = uibutton(app.SystemSetupPanel, 'push');
            app.InitializeSystemButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeSystemButtonPushed, true);
            app.InitializeSystemButton.Text = 'Initialize System';
            app.AxisResetButton = uibutton(app.SystemSetupPanel, 'push');
            app.AxisResetButton.ButtonPushedFcn = createCallbackFcn(app, @AxisResetButtonPushed, true);
            app.AxisResetButton.Text = 'Axis Reset';
            app.Axis1Panel_2 = uipanel(app.SystemSetupPanel);
            app.Axis1Panel_2.Title = 'Axis 1';
            app.KpDropDownLabel = uilabel(app.Axis1Panel_2);
            app.KpDropDownLabel.Text = 'Kp';
            app.KpDropDown_1 = uidropdown(app.Axis1Panel_2);
            app.KpDropDown_1.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KpDropDown_1.Value = '4';
            app.KvDropDownLabel = uilabel(app.Axis1Panel_2);
            app.KvDropDownLabel.Text = 'Kv';
            app.KvDropDown_1 = uidropdown(app.Axis1Panel_2);
            app.KvDropDown_1.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KvDropDown_1.Value = '4';
            app.DirDropDownLabel = uilabel(app.Axis1Panel_2);
            app.DirDropDownLabel.Text = 'Dir';
            app.DirDropDown_1 = uidropdown(app.Axis1Panel_2);
            app.DirDropDown_1.Items = {'0', '1'};
            app.DirDropDown_1.Value = '0';
            app.Axis2Panel_2 = uipanel(app.SystemSetupPanel);
            app.Axis2Panel_2.Title = 'Axis 2';
            app.KpDropDown_2Label = uilabel(app.Axis2Panel_2);
            app.KpDropDown_2Label.Text = 'Kp';
            app.KpDropDown_2 = uidropdown(app.Axis2Panel_2);
            app.KpDropDown_2.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KpDropDown_2.Value = '4';
            app.KvDropDown_2Label = uilabel(app.Axis2Panel_2);
            app.KvDropDown_2Label.Text = 'Kv';
            app.KvDropDown_2 = uidropdown(app.Axis2Panel_2);
            app.KvDropDown_2.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KvDropDown_2.Value = '4';
            app.DirDropDown_2Label = uilabel(app.Axis2Panel_2);
            app.DirDropDown_2Label.Text = 'Dir';
            app.DirDropDown_2 = uidropdown(app.Axis2Panel_2);
            app.DirDropDown_2.Items = {'0', '1'};
            app.DirDropDown_2.Value = '0';
            app.Axis3Panel_2 = uipanel(app.SystemSetupPanel);
            app.Axis3Panel_2.Title = 'Axis 3';
            app.KpDropDown_3Label = uilabel(app.Axis3Panel_2);
            app.KpDropDown_3Label.Text = 'Kp';
            app.KpDropDown_3 = uidropdown(app.Axis3Panel_2);
            app.KpDropDown_3.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KpDropDown_3.Value = '4';
            app.KvDropDown_3Label = uilabel(app.Axis3Panel_2);
            app.KvDropDown_3Label.Text = 'Kv';
            app.KvDropDown_3 = uidropdown(app.Axis3Panel_2);
            app.KvDropDown_3.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KvDropDown_3.Value = '4';
            app.DirDropDown_3Label = uilabel(app.Axis3Panel_2);
            app.DirDropDown_3Label.Text = 'Dir';
            app.DirDropDown_3 = uidropdown(app.Axis3Panel_2);
            app.DirDropDown_3.Items = {'0', '1'};
            app.DirDropDown_3.Value = '0';
            app.Axis4Panel_2 = uipanel(app.SystemSetupPanel);
            app.Axis4Panel_2.Title = 'Axis 4';
            app.KpDropDown_4Label = uilabel(app.Axis4Panel_2);
            app.KpDropDown_4Label.Text = 'Kp';
            app.KpDropDown_4 = uidropdown(app.Axis4Panel_2);
            app.KpDropDown_4.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KpDropDown_4.Value = '4';
            app.KvDropDown_4Label = uilabel(app.Axis4Panel_2);
            app.KvDropDown_4Label.Text = 'Kv';
            app.KvDropDown_4 = uidropdown(app.Axis4Panel_2);
            app.KvDropDown_4.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KvDropDown_4.Value = '4';
            app.DirDropDown_4Label = uilabel(app.Axis4Panel_2);
            app.DirDropDown_4Label.Text = 'Dir';
            app.DirDropDown_4 = uidropdown(app.Axis4Panel_2);
            app.DirDropDown_4.Items = {'0', '1'};
            app.DirDropDown_4.Value = '0';
            app.Axis5Panel_2 = uipanel(app.SystemSetupPanel);
            app.Axis5Panel_2.Title = 'Axis 5';
            app.KpDropDown_5Label = uilabel(app.Axis5Panel_2);
            app.KpDropDown_5Label.Text = 'Kp';
            app.KpDropDown_5 = uidropdown(app.Axis5Panel_2);
            app.KpDropDown_5.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KpDropDown_5.Value = '4';
            app.KvDropDown_5Label = uilabel(app.Axis5Panel_2);
            app.KvDropDown_5Label.Text = 'Kv';
            app.KvDropDown_5 = uidropdown(app.Axis5Panel_2);
            app.KvDropDown_5.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KvDropDown_5.Value = '4';
            app.DirDropDown_5Label = uilabel(app.Axis5Panel_2);
            app.DirDropDown_5Label.Text = 'Dir';
            app.DirDropDown_5 = uidropdown(app.Axis5Panel_2);
            app.DirDropDown_5.Items = {'0', '1'};
            app.DirDropDown_5.Value = '0';
            app.Axis6Panel_2 = uipanel(app.SystemSetupPanel);
            app.Axis6Panel_2.Title = 'Axis 6';
            app.KpDropDown_6Label = uilabel(app.Axis6Panel_2);
            app.KpDropDown_6Label.Text = 'Kp';
            app.KpDropDown_6 = uidropdown(app.Axis6Panel_2);
            app.KpDropDown_6.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KpDropDown_6.Value = '4';
            app.KvDropDown_6Label = uilabel(app.Axis6Panel_2);
            app.KvDropDown_6Label.Text = 'Kv';
            app.KvDropDown_6 = uidropdown(app.Axis6Panel_2);
            app.KvDropDown_6.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KvDropDown_6.Value = '4';
            app.DirDropDown_6Label = uilabel(app.Axis6Panel_2);
            app.DirDropDown_6Label.Text = 'Dir';
            app.DirDropDown_6 = uidropdown(app.Axis6Panel_2);
            app.DirDropDown_6.Items = {'0', '1'};
            app.DirDropDown_6.Value = '0';
            app.Axis7Panel_2 = uipanel(app.SystemSetupPanel);
            app.Axis7Panel_2.Title = 'Axis 7';
            app.KpDropDown_7Label = uilabel(app.Axis7Panel_2);
            app.KpDropDown_7Label.Text = 'Kp';
            app.KpDropDown_7 = uidropdown(app.Axis7Panel_2);
            app.KpDropDown_7.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KpDropDown_7.Value = '4';
            app.KvDropDown_7Label = uilabel(app.Axis7Panel_2);
            app.KvDropDown_7Label.Text = 'Kv';
            app.KvDropDown_7 = uidropdown(app.Axis7Panel_2);
            app.KvDropDown_7.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KvDropDown_7.Value = '4';
            app.DirDropDown_7Label = uilabel(app.Axis7Panel_2);
            app.DirDropDown_7Label.Text = 'Dir';
            app.DirDropDown_7 = uidropdown(app.Axis7Panel_2);
            app.DirDropDown_7.Items = {'0', '1'};
            app.DirDropDown_7.Value = '0';
            app.Axis8Panel_2 = uipanel(app.SystemSetupPanel);
            app.Axis8Panel_2.Title = 'Axis 8';
            app.KpDropDown_8Label = uilabel(app.Axis8Panel_2);
            app.KpDropDown_8Label.Text = 'Kp';
            app.KpDropDown_8 = uidropdown(app.Axis8Panel_2);
            app.KpDropDown_8.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KpDropDown_8.Value = '4';
            app.KvDropDown_8Label = uilabel(app.Axis8Panel_2);
            app.KvDropDown_8Label.Text = 'Kv';
            app.KvDropDown_8 = uidropdown(app.Axis8Panel_2);
            app.KvDropDown_8.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.KvDropDown_8.Value = '4';
            app.DirDropDown_8Label = uilabel(app.Axis8Panel_2);
            app.DirDropDown_8Label.Text = 'Dir';
            app.DirDropDown_8 = uidropdown(app.Axis8Panel_2);
            app.DirDropDown_8.Items = {'0', '1'};
            app.DirDropDown_8.Value = '0';
            app.NumberofSamplesKnobLabel = uilabel(app.SystemSetupPanel);
            app.NumberofSamplesKnobLabel.Text = 'Number of Samples';
            app.NumberofSamplesKnob = uiknob(app.SystemSetupPanel, 'discrete');
            app.NumberofSamplesKnob.Items = {'10', '100', '1000', '10000'};
            app.NumberofSamplesKnob.ValueChangedFcn = createCallbackFcn(app, @NumberofSamplesKnobValueChanged, true);
            app.NumberofSamplesKnob.Value = '100';
            app.HumidityEditField = uieditfield(app.SystemSetupPanel, 'numeric');
            app.HumidityEditField.ValueChangedFcn = createCallbackFcn(app, @HumidityEditFieldValueChanged, true);
            app.HumidityEditField.Value = 50;
            app.TemperatureEditField = uieditfield(app.SystemSetupPanel, 'numeric');
            app.TemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureEditFieldValueChanged, true);
            app.TemperatureEditField.Value = 20;
            app.TemperatureDegCGaugeLabel = uilabel(app.SystemSetupPanel);
            app.TemperatureDegCGaugeLabel.Text = 'Temperature (Deg C)';
            app.TemperatureGauge = uigauge(app.SystemSetupPanel, 'circular');
            app.TemperatureGauge.Limits = [0 45];
            app.TemperatureGauge.Value = 20;
            app.PressuremmHgGaugeLabel = uilabel(app.SystemSetupPanel);
            app.PressuremmHgGaugeLabel.Text = 'Pressure (mm Hg)';
            app.PressureGauge = uigauge(app.SystemSetupPanel, 'circular');
            app.PressureGauge.Limits = [700 800];
            app.PressureGauge.Value = 760;
            app.HumidityGaugeLabel = uilabel(app.SystemSetupPanel);
            app.HumidityGaugeLabel.Text = 'Humidity (%)';
            app.HumidityGauge = uigauge(app.SystemSetupPanel, 'circular');
            app.HumidityGauge.Limits = [20 80];
            app.HumidityGauge.Value = 50;
            app.PressureEditField = uieditfield(app.SystemSetupPanel, 'numeric');
            app.PressureEditField.ValueChangedFcn = createCallbackFcn(app, @PressureEditFieldValueChanged, true);
            app.PressureEditField.Value = 760;
            app.ZygoOffButton = uibutton(app.SystemSetupPanel, 'push');
            app.ZygoOffButton.ButtonPushedFcn = createCallbackFcn(app, @ZygoOffButtonPushed, true);
            app.ZygoOffButton.Text = 'Zygo Off';
            app.SystemStatusPanel = uipanel(app.ZygoLaserMeasurementSystemProgrammedbyAmmarAlJodahUIFigure);
            app.SystemStatusPanel.Title = 'System Status';
            app.Panel_2 = uipanel(app.SystemStatusPanel);
            app.MeasurementSignalErrorLabel = uilabel(app.Panel_2);
            app.MeasurementSignalErrorLabel.Text = 'Measurement Signal Error';
            app.Panel_3 = uipanel(app.SystemStatusPanel);
            app.ReferenceSignalErrorLabel = uilabel(app.Panel_3);
            app.ReferenceSignalErrorLabel.Text = 'Reference Signal Error';
            app.Panel_4 = uipanel(app.SystemStatusPanel);
            app.MeasurementErrorLabel = uilabel(app.Panel_4);
            app.MeasurementErrorLabel.Text = 'Measurement Error';
            app.Panel_5 = uipanel(app.SystemStatusPanel);
            app.ReferenceErrorLabel = uilabel(app.Panel_5);
            app.ReferenceErrorLabel.Text = 'Reference Error';
            app.Panel_6 = uipanel(app.SystemStatusPanel);
            app.OverflowErrorLabel = uilabel(app.Panel_6);
            app.OverflowErrorLabel.Text = 'Overflow Error ';
            app.Panel_7 = uipanel(app.SystemStatusPanel);
            app.VelocityErrorLabel = uilabel(app.Panel_7);
            app.VelocityErrorLabel.Text = 'Velocity Error';
            app.Axis1Panel = uipanel(app.SystemStatusPanel);
            app.Axis1Panel.Title = 'Axis 1';
            app.LedMSE = uilamp(app.Axis1Panel);
            app.LedRSE = uilamp(app.Axis1Panel);
            app.LedME = uilamp(app.Axis1Panel);
            app.LedRE = uilamp(app.Axis1Panel);
            app.LedOFE = uilamp(app.Axis1Panel);
            app.LedVE = uilamp(app.Axis1Panel);
            app.Axis2Panel = uipanel(app.SystemStatusPanel);
            app.Axis2Panel.Title = 'Axis 2';
            app.LedMSE_2 = uilamp(app.Axis2Panel);
            app.LedRSE_2 = uilamp(app.Axis2Panel);
            app.LedME_2 = uilamp(app.Axis2Panel);
            app.LedRE_2 = uilamp(app.Axis2Panel);
            app.LedOFE_2 = uilamp(app.Axis2Panel);
            app.LedVE_2 = uilamp(app.Axis2Panel);
            app.Axis3Panel = uipanel(app.SystemStatusPanel);
            app.Axis3Panel.Title = 'Axis 3';
            app.LedMSE_3 = uilamp(app.Axis3Panel);
            app.LedRSE_3 = uilamp(app.Axis3Panel);
            app.LedME_3 = uilamp(app.Axis3Panel);
            app.LedRE_3 = uilamp(app.Axis3Panel);
            app.LedOFE_3 = uilamp(app.Axis3Panel);
            app.LedVE_3 = uilamp(app.Axis3Panel);
            app.Axis4Panel = uipanel(app.SystemStatusPanel);
            app.Axis4Panel.Title = 'Axis 4';
            app.LedMSE_4 = uilamp(app.Axis4Panel);
            app.LedRSE_4 = uilamp(app.Axis4Panel);
            app.LedME_4 = uilamp(app.Axis4Panel);
            app.LedRE_4 = uilamp(app.Axis4Panel);
            app.LedOFE_4 = uilamp(app.Axis4Panel);
            app.LedVE_4 = uilamp(app.Axis4Panel);
            app.Axis5Panel = uipanel(app.SystemStatusPanel);
            app.Axis5Panel.Title = 'Axis 5';
            app.LedMSE_5 = uilamp(app.Axis5Panel);
            app.LedRSE_5 = uilamp(app.Axis5Panel);
            app.LedME_5 = uilamp(app.Axis5Panel);
            app.LedRE_5 = uilamp(app.Axis5Panel);
            app.LedOFE_5 = uilamp(app.Axis5Panel);
            app.LedVE_5 = uilamp(app.Axis5Panel);
            app.Axis6Panel = uipanel(app.SystemStatusPanel);
            app.Axis6Panel.Title = 'Axis 6';
            app.LedMSE_6 = uilamp(app.Axis6Panel);
            app.LedRSE_6 = uilamp(app.Axis6Panel);
            app.LedME_6 = uilamp(app.Axis6Panel);
            app.LedRE_6 = uilamp(app.Axis6Panel);
            app.LedOFE_6 = uilamp(app.Axis6Panel);
            app.LedVE_6 = uilamp(app.Axis6Panel);
            app.Axis7Panel = uipanel(app.SystemStatusPanel);
            app.Axis7Panel.Title = 'Axis 7';
            app.LedMSE_7 = uilamp(app.Axis7Panel);
            app.LedRSE_7 = uilamp(app.Axis7Panel);
            app.LedME_7 = uilamp(app.Axis7Panel);
            app.LedRE_7 = uilamp(app.Axis7Panel);
            app.LedOFE_7 = uilamp(app.Axis7Panel);
            app.LedVE_7 = uilamp(app.Axis7Panel);
            app.Axis8Panel = uipanel(app.SystemStatusPanel);
            app.Axis8Panel.Title = 'Axis 8';
            app.LedMSE_8 = uilamp(app.Axis8Panel);
            app.LedRSE_8 = uilamp(app.Axis8Panel);
            app.LedME_8 = uilamp(app.Axis8Panel);
            app.LedRE_8 = uilamp(app.Axis8Panel);
            app.LedOFE_8 = uilamp(app.Axis8Panel);
            app.LedVE_8 = uilamp(app.Axis8Panel);
            app.Panel_8 = uipanel(app.SystemStatusPanel);
            app.ErrorTypeLabel = uilabel(app.Panel_8);
            app.ErrorTypeLabel.Text = 'Error Type';
            app.SystemMessagesPanel = uipanel(app.ZygoLaserMeasurementSystemProgrammedbyAmmarAlJodahUIFigure);
            app.SystemMessagesPanel.Title = 'System Messages';
            app.ListBox = uilistbox(app.SystemMessagesPanel);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.AxisLiveUpdatePanel = uipanel(app.ZygoLaserMeasurementSystemProgrammedbyAmmarAlJodahUIFigure);
            app.AxisLiveUpdatePanel.Title = 'Axis Live Update';
            app.Axis1positionEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis2positionEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis3positionEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis4positionEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis5positionEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis6positionEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis7positionEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis8positionEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis1velocityEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis2velocityEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis3velocityEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis4velocityEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis5velocityEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis6velocityEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis7velocityEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.Axis8velocityEditField = uieditfield(app.AxisLiveUpdatePanel, 'numeric');
            app.AxisLabel = uilabel(app.AxisLiveUpdatePanel);
            app.AxisLabel.Text = 'Axis';
            app.PositionLabel = uilabel(app.AxisLiveUpdatePanel);
            app.PositionLabel.Text = 'Position';
            app.VelocityLabel = uilabel(app.AxisLiveUpdatePanel);
            app.VelocityLabel.Text = 'Velocity';
            app.Label = uilabel(app.AxisLiveUpdatePanel);
            app.Label.Text = '1';
            app.Label_3 = uilabel(app.AxisLiveUpdatePanel);
            app.Label_3.Text = '2';
            app.Label_4 = uilabel(app.AxisLiveUpdatePanel);
            app.Label_4.Text = '3';
            app.Label_5 = uilabel(app.AxisLiveUpdatePanel);
            app.Label_5.Text = '4';
            app.Label_6 = uilabel(app.AxisLiveUpdatePanel);
            app.Label_6.Text = '5';
            app.Label_7 = uilabel(app.AxisLiveUpdatePanel);
            app.Label_7.Text = '6';
            app.Label_8 = uilabel(app.AxisLiveUpdatePanel);
            app.Label_8.Text = '7';
            app.Label_9 = uilabel(app.AxisLiveUpdatePanel);
            app.Label_9.Text = '8';
            app.Axis1Switch = uiswitch(app.AxisLiveUpdatePanel, 'slider');
            app.Axis2Switch = uiswitch(app.AxisLiveUpdatePanel, 'slider');
            app.Axis3Switch = uiswitch(app.AxisLiveUpdatePanel, 'slider');
            app.Axis4Switch = uiswitch(app.AxisLiveUpdatePanel, 'slider');
            app.Axis5Switch = uiswitch(app.AxisLiveUpdatePanel, 'slider');
            app.Axis6Switch = uiswitch(app.AxisLiveUpdatePanel, 'slider');
            app.Axis7Switch = uiswitch(app.AxisLiveUpdatePanel, 'slider');
            app.Axis8Switch = uiswitch(app.AxisLiveUpdatePanel, 'slider');
            app.PlotPositionsSwitchLabel = uilabel(app.AxisLiveUpdatePanel);
            app.PlotPositionsSwitchLabel.Text = 'Plot Positions';
            app.PlotPositionsSwitch = uiswitch(app.AxisLiveUpdatePanel, 'toggle');
            app.PlotPositionsSwitch.ValueChangedFcn = createCallbackFcn(app, @PlotPositionsSwitchValueChanged, true);
            app.Panel_9 = uipanel(app.ZygoLaserMeasurementSystemProgrammedbyAmmarAlJodahUIFigure);
            app.ZygoLaserMeasurementSystemLabel_2 = uilabel(app.Panel_9);
            app.ZygoLaserMeasurementSystemLabel_2.Text = 'Zygo Laser Measurement System';
            app.programmedbyAmmarAlJodahLabel = uilabel(app.Panel_9);
            app.programmedbyAmmarAlJodahLabel.Text = 'programmed by: Ammar Al-Jodah';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Zygo_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZygoLaserMeasurementSystemProgrammedbyAmmarAlJodahUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZygoLaserMeasurementSystemProgrammedbyAmmarAlJodahUIFigure)
        end
    end
end